classdef MiniMandrake < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        ThirdstepfilteringanddifferentialPanel  matlab.ui.container.Panel
        SeediffButton                  matlab.ui.control.Button
        LampFilterMain                 matlab.ui.control.Lamp
        LowpassanddifferentialButton   matlab.ui.control.Button
        FilterOrder                    matlab.ui.control.NumericEditField
        FilterorderEditFieldLabel      matlab.ui.control.Label
        Low1                           matlab.ui.control.NumericEditField
        FIlterLabel                    matlab.ui.control.Label
        High1                          matlab.ui.control.NumericEditField
        SecondstepoptionalcorrofbigillchangesPanel  matlab.ui.container.Panel
        DetrendOrdMain                 matlab.ui.control.NumericEditField
        SeezprojButton                 matlab.ui.control.Button
        LampSubAvg                     matlab.ui.control.Lamp
        DetrendingButton               matlab.ui.control.Button
        FirststepUploadPanel           matlab.ui.container.Panel
        FieldnumberDropDown            matlab.ui.control.DropDown
        FieldnumberDropDownLabel       matlab.ui.control.Label
        ExperimentnumberDropDown       matlab.ui.control.DropDown
        ExperimentnumberDropDownLabel  matlab.ui.control.Label
        FPSEditFieldMain               matlab.ui.control.NumericEditField
        FPSEditField_3Label_3          matlab.ui.control.Label
        Loadmasks                      matlab.ui.control.Button
        filenametext                   matlab.ui.control.Label
        filename                       matlab.ui.control.EditField
        Datatype                       matlab.ui.control.DropDown
        DatatypeDropDownLabel          matlab.ui.control.Label
        SelectfolderandfileButton      matlab.ui.control.Button
        VideoPanel                     matlab.ui.container.Panel
        FourthstepROIsgenerationPanel  matlab.ui.container.Panel
        LampSaveRes                    matlab.ui.control.Lamp
        LampSaveROI                    matlab.ui.control.Lamp
        SaveResultsButton              matlab.ui.control.Button
        SaveROIsButton                 matlab.ui.control.Button
        MinAreaThr                     matlab.ui.control.NumericEditField
        MinAreaThrLabel                matlab.ui.control.Label
        SeeBinarizedButton             matlab.ui.control.Button
        ManualROIsPanel                matlab.ui.container.Panel
        greenDeleteROIsButton          matlab.ui.control.Button
        NofROIsinthisfield             matlab.ui.control.NumericEditField
        NofROIsinthisfieldEditFieldLabel  matlab.ui.control.Label
        DrawROIsButton                 matlab.ui.control.Button
        Erode                          matlab.ui.control.NumericEditField
        ErodeEditFieldLabel            matlab.ui.control.Label
        Dilate                         matlab.ui.control.NumericEditField
        DilateEditFieldLabel           matlab.ui.control.Label
        nhood                          matlab.ui.control.NumericEditField
        nhoodEditFieldLabel            matlab.ui.control.Label
        greenThrButtonGroup            matlab.ui.container.ButtonGroup
        AdaptiveButton                 matlab.ui.control.RadioButton
        GlobalButton                   matlab.ui.control.RadioButton
        autorois                       matlab.ui.control.Button
        ThreshSlider                   matlab.ui.control.Slider
        LUTmodifierandsliderPanel      matlab.ui.container.Panel
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        greenLUTPanel                  matlab.ui.container.Panel
        CMaxEditField                  matlab.ui.control.NumericEditField
        maxEditFieldLabel              matlab.ui.control.Label
        CMinEditField                  matlab.ui.control.NumericEditField
        minLabel                       matlab.ui.control.Label
        greenHistoAxes                 matlab.ui.control.UIAxes
        legendAxes                     matlab.ui.control.UIAxes
        greench                        matlab.ui.control.UIAxes
        RawprofilesTab                 matlab.ui.container.Tab
        CropPanel                      matlab.ui.container.Panel
        RestoreButton                  matlab.ui.control.Button
        CROPButton                     matlab.ui.control.Button
        EndsEditField                  matlab.ui.control.NumericEditField
        EndsEditFieldLabel             matlab.ui.control.Label
        StartsEditField                matlab.ui.control.NumericEditField
        StartsEditFieldLabel           matlab.ui.control.Label
        PlotRawButton                  matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        RawTimeseries                  matlab.ui.control.UIAxes
        ProfilesTab                    matlab.ui.container.Tab
        VisualizationanddataextractionPanel  matlab.ui.container.Panel
        TextArea_3                     matlab.ui.control.TextArea
        ROIscleaningPanel              matlab.ui.container.Panel
        DeletecurrentROIButton         matlab.ui.control.Button
        RemoveallsubthrROIsButton      matlab.ui.control.Button
        ThresholdROItimecourse         matlab.ui.control.NumericEditField
        ThresholdzscoreEditFieldLabel  matlab.ui.control.Label
        OscillatoryactivityPanel       matlab.ui.container.Panel
        MinDistance                    matlab.ui.control.NumericEditField
        MinDistancesLabel              matlab.ui.control.Label
        ProminenceSwitch               matlab.ui.control.Switch
        AutodetectionallROIs           matlab.ui.control.Button
        ManualpeakPanel                matlab.ui.container.Panel
        ValidateWindow                 matlab.ui.control.Button
        window                         matlab.ui.control.EditField
        OscEnd                         matlab.ui.control.NumericEditField
        OscStart                       matlab.ui.control.NumericEditField
        Ythreshold                     matlab.ui.control.NumericEditField
        YThresholdLabel                matlab.ui.control.Label
        FilteringPanel                 matlab.ui.container.Panel
        FilterOrder2                   matlab.ui.control.NumericEditField
        FilterOrderLabel               matlab.ui.control.Label
        Low2                           matlab.ui.control.NumericEditField
        BPFilterEditFieldLabel         matlab.ui.control.Label
        High2                          matlab.ui.control.NumericEditField
        SeeButton                      matlab.ui.control.Button
        AvgFwindow                     matlab.ui.control.NumericEditField
        AvgFwindowsEditFieldLabel      matlab.ui.control.Label
        ValidateOsc                    matlab.ui.control.Button
        ShowROIsub                     matlab.ui.control.Spinner
        ShowROInSpinner_2Label         matlab.ui.control.Label
        ShowROInorm                    matlab.ui.control.Spinner
        ShowROInSpinnerLabel           matlab.ui.control.Label
        SavenormalizedButton           matlab.ui.control.Button
        Detrendorder                   matlab.ui.control.NumericEditField
        DetrendorderEditFieldLabel     matlab.ui.control.Label
        PlotNormalizedButton           matlab.ui.control.Button
        PlotDetrendedButton            matlab.ui.control.Button
        ResultAxes                     matlab.ui.control.UIAxes
        SubtractedTimeseries           matlab.ui.control.UIAxes
        NormalizedTimeseries           matlab.ui.control.UIAxes
        Tabs                           matlab.ui.container.Tab
        infoTextArea                   matlab.ui.control.TextArea
        infoTextAreaLabel              matlab.ui.control.Label
        ROInEditField                  matlab.ui.control.NumericEditField
        ROInEditFieldLabel             matlab.ui.control.Label
        greenCopyTable_2               matlab.ui.control.Button
        ResultsTable                   matlab.ui.control.Table
        greenCopyTable                 matlab.ui.control.Button
        TimeseriesTable                matlab.ui.control.Table
    end

    
    properties (Access = public)
        filepath
        selection
        ExpArray
        ExpStruct
        SubfieldStruct
        allFileNames
        fullFileName
        singlemask
        sz
        chAxes
        cmin
        cmax 
        histoaxes 
        chImg
        chMasks
        img
        timeseriesdataset
        resultsdataset
        legendimg
        xmarker
        ymarker
        refposAxes
        STxCorr
        lagTime
        time
        freq 
        samplingperiod
        tstart
        tend
        temp % temporary struct to store the original timeseries before cropping
        tempbkg
        lowvideo
        der_zproj
        binarized
        first 
        last
        a
        b
        C
    end
    
    methods (Access = public)


        function Files(app)
            if strcmp(app.Datatype.Value,'TIF list') == 1
                % select the directory
                directory = uigetdir();           

                % Process a sequence of files.
                filePattern = fullfile(directory, {'*.tif';'*.tiff'});
                imds = imageDatastore(filePattern); % Create an image Datastore
                % Get all filenames into one cell array.  Filenames have the complete path (folder prepended).
                app.allFileNames = natsortfiles(imds.Files); % creating the cell array and sorting it with natural order!!!!
                numFiles = numel(imds.Files);
                for k = 1 : numel(app.allFileNames) 
                    % Get this file name.
                    app.fullFileName = app.allFileNames{k};
                    fprintf('Processing %s\n', app.fullFileName);
                    % Now do something with fullFileName, such as passing it to imread.
                end
           
            else %strcmp(app.Datatype.Value,'multiTIF (one exp)') == 1 
                % select the image 
                %directory = uigetdir();           
                [app.selection,app.filepath] = uigetfile ({'*.tif';'*.cxd';'*.tiff'});
                app.filename.Value = app.selection;
             
            %elseif strcmp(app.Datatype.Value,'multiTIF (multi exp)') == 1  
                % select the image 
            %    directory = uigetdir();                  
            end 
        end

        function Sorter(app)
            if strcmp(app.Datatype.Value,'TIF list') == 1
                app.singlemask = imread(app.allFileNames{1}); % read a single masks
                app.sz = size(app.singlemask); % get the size of the masks 
    
                
                app.ExpStruct = struct('exp',cell(app.NumberOfExp.Value,1)); % create a structure with experiments
    
                % set the number of field for each experiment
                for i = 1:app.NumberOfExp.Value
    
                    numberoffield = zeros(1,size(app.allFileNames,1));
                    numberofframe = zeros(1,size(app.allFileNames,1));
    
                    for j = 1 : numel(app.allFileNames)
    
                        splitdir = regexp(app.allFileNames{j},'\','split');
                        filename = splitdir{end}; % extract filename
                        %filename = join(string(filename));
                        identifiers = regexp(filename,'\d*','Match');
    
                        numberofexp = str2num(identifiers{1});
    
                        if numberofexp == i
                            numberoffield(j) = str2num(identifiers{2});
                            numberofframe(j) = str2num(identifiers{3});
                        end
                    end
    
                    app.ExpStruct(i).exp = struct('subfield',cell(max(numberoffield),1), 'maskstimecourse',cell(max(numberoffield),1), ...
                        'background',cell(max(numberoffield),1), 'subtracted',cell(max(numberoffield),1), ...
                        'meanbackground_fromsubtractedtimeseries',cell(max(numberoffield),1), 'normalized',cell(max(numberoffield),1), ...
                        'max',cell(max(numberoffield),1),'auc',cell(max(numberoffield),1), 'roi',cell(max(numberoffield),1), ...
                        'upstates',cell(max(numberoffield),1));       


                end
                
                numberofexp = [];
                numberoffield = [];
                numberofframe = [];
                % load the frames in the structure
                for i = 1 : numel(app.allFileNames)
                    splitdir = regexp(app.allFileNames{i},'\','split');
                    filename = splitdir{end}; % extract filename
                    identifiers = regexp(filename,'\d*','Match');
    
                    % get number of experiment, subfield and timestep of file i
                    numberofexp = str2num(identifiers{1});
                    numberoffield = str2num(identifiers{2});
                    numberofframe = str2num(identifiers{3});
                    
                    % load frame and sort it
                    frame = imread(app.allFileNames{i});
                    app.ExpStruct(numberofexp).exp(numberoffield).subfield(:,:,numberofframe) = double(frame);
    
                end
    
                app.ExperimentnumberDropDown.Value = '1';
                app.FieldnumberDropDown.Value = '1';
                app.Slider.Value = 1;
                Displayimage(app, str2double(app.ExperimentnumberDropDown.Value), str2double(app.FieldnumberDropDown.Value), app.Slider.Value)
    
                explist = (1:numberofexp);
                explist = arrayfun(@num2str,explist,'un',0);
                app.ExperimentnumberDropDown.Items = explist;

                fieldlist = (1:size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp,1));
                fieldlist = arrayfun(@num2str,fieldlist,'un',0);            
                app.FieldnumberDropDown.Items = fieldlist;                

            else %strcmp(app.Datatype.Value,'multiTIF (one exp)') == 1 

                app.singlemask = imread(fullfile(app.filepath, app.selection),1); % read a single masks
                app.sz = size(app.singlemask); % get the size of the masks    
                

                app.ExpStruct = struct('exp',cell(1,1)); % create a structure with experiments

                app.ExpStruct.exp = struct('subfield',cell(1,1), 'maskstimecourse',cell(1,1), 'background',cell(1,1), ...
                    'subtracted',cell(1,1),'meanbackground_fromsubtractedtimeseries',cell(1,1), 'normalized',cell(1,1), ...
                    'max',cell(1,1), 'auc',cell(1,1),'roi',cell(1,1),'upstates',cell(1,1),'centroid',cell(1,1),'DynROI',cell(1,1),'DynROIrgb',cell(1,1));       
                
                i = 1;
                while true
                    try
                        field = imread(fullfile(app.filepath, app.selection),i);                    
                    catch
                        break
                    end
                    app.ExpStruct(1).exp(1).subfield(:,:,i) = double(field);
                    i = i + 1;
                end

                % set at 1 the field number and the frame
                app.FieldnumberDropDown.Value = '1';
                app.Slider.Value = 1;

                % launch the displayer
                Displayimage(app, str2double(app.ExperimentnumberDropDown.Value), str2double(app.FieldnumberDropDown.Value), app.Slider.Value)
    
                % set only 1 experiment
                explist = (1);
                explist = arrayfun(@num2str,explist,'un',0);
                app.ExperimentnumberDropDown.Items = explist;

            end

        end

        function Displayimage(app,exp,subfield,frame)

            frame = floor(frame);
            
            if size(app.ExpStruct(exp).exp(subfield).subfield(:,:,frame),2) ~= 0

                app.LampFilterMain.Color = 'red';
                app.LampSubAvg.Color = 'red'; 
                app.LampSaveROI.Color = 'red';
                app.LampSaveRes.Color = 'red'; 

                %calculate timecourse
                app.freq = app.FPSEditFieldMain.Value;
                app.samplingperiod = 1/app.freq;
                app.time = 0:app.samplingperiod:(size(app.ExpStruct(exp).exp(subfield).subfield,3)/app.freq)-app.samplingperiod;
                app.tstart = app.time(1);
                app.tend = app.time(end);
                %app.Slider2.Limits = [app.tstart app.tend];

                % set axes limits
                app.chAxes.XLim = [1,size(app.ExpStruct(exp).exp(subfield).subfield(:,:,frame),2)];
                app.chAxes.YLim = [1,size(app.ExpStruct(exp).exp(subfield).subfield(:,:,frame),1)];
    

                %display image
                app.img = app.ExpStruct(exp).exp(subfield).subfield(:,:,frame);            
                app.chImg.CData = app.img;
                colormap(app.chAxes,'gray');

                % get the size
                app.sz = size(app.ExpStruct(exp).exp(subfield).subfield);

                % clear masks
                app.chMasks.CData = [];
                cla(app.legendAxes)


                % display the masks if you created some and display the legend
                if isempty(app.ExpStruct(exp).exp(subfield).roi) == 0
    
                    % creating and plotting transparent masks
                    twoDimTransparencyComp(app,app.ExpStruct(exp).exp(subfield).roi)
    
                    %legend 
                    Legend(app,app.legendAxes,app.ExpStruct(exp).exp(subfield).roi,app.C)


                    % update the list of rois in the cross correlation
                    roilist = (1:size(app.ExpStruct(exp).exp(subfield).roi,3));
                    roilist = arrayfun(@num2str,roilist,'un',0);
                    app.Data1ROIDropDown.Items = roilist;
                    app.Data2DropDown.Items = roilist;
                    %app.ShowROIsub.Items = roilist;
                    %app.ShowROInorm.Items = roilist; 


                end

    
                % update histogram and lut values
                limits = quantile(app.ExpStruct(exp).exp(subfield).subfield(:,:,frame),[0.025,0.975],'all');
                histogram(app.histoaxes,app.ExpStruct(exp).exp(subfield).subfield(:,:,frame),linspace(limits(1),limits(2),uint16(512)));
                app.histoaxes.XLim = [limits(1), limits(2)];
                app.histoaxes.YLimMode = 'auto';
                % if channel == 1
                app.CMinEditField.Value = limits(1);
                app.CMaxEditField.Value = limits(2);
                % else
                %     app.redCMinEditField.Value = limits(1);
                %     app.redCMaxEditField.Value = limits(2);maskdrawer
                % end    

                app.Slider.Limits = [1 size(app.ExpStruct(exp).exp(subfield).subfield,3)];
                
    
                %update number of roi edit field
                app.NofROIsinthisfield.Value = size(app.ExpStruct(exp).exp(subfield).roi,3);
                if isempty(app.ExpStruct(exp).exp(subfield).roi)
                    app.NofROIsinthisfield.Value = 0;
                end
    
                % return to the app
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
            else
                warning('No figure, you may have selected a subfield that is not present')
            end

        end


        function MaskDrawer(app,mode,exp,subfield)

            % function to update the displayed image in the temporary
            % figure
            function Update(src,event,interactivefig,struct)
                val = round(event.Value);
                interactivefig.CData = struct(:,:,val);
            end
            % Create ValueChangedFcn callback
            function editFieldValueChanged(src,event,ax,mode)
                g = src.Value;
                if mode == 1
                    ax.CLim(1) = g;
                elseif mode == 2
                    ax.CLim(2) = g;
                end
            end

            % create an interactive window
            fig1 = figure(1);
            set(fig1, 'Units', 'Normalized', 'OuterPosition', [0, 0.1, 0.7, 0.9]);
            ax = axes(fig1);
            colormap(ax,"gray")
            interactivefig = imagesc(ax,app.img);
            interactivefig.CData = app.img;
            colormap(ax,"gray")

            %ax.CLim = [app.CMinEditField.Value, app.CMaxEditField.Value];

            fig2 = uifigure();
            fig2.Units = 'Normalized';
            fig2.Position = [0.7, 0.5, 0.3, 0.3];
            
            video = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subfield;
            sld = uislider(fig2,"Value",1,"ValueChangingFcn",@(src,event)Update(src,event,interactivefig,video));
            sld.Limits = [1 size(app.ExpStruct(exp).exp(subfield).subfield,3)]; 
            
            fig3 = uifigure();
            fig3.Units = 'Normalized';
            fig3.Position = [0.7, 0.1, 0.3, 0.3];            
            editfield1 = uieditfield(fig3,'numeric',"Value",app.CMinEditField.Value,"ValueChangedFcn",@(src,event)editFieldValueChanged(src,event,ax,1));          
            editfield2 = uieditfield(fig3,'numeric',"Value",app.CMaxEditField.Value,"ValueChangedFcn",@(src,event)editFieldValueChanged(src,event,ax,2));          
            editfield1.Position = [100 100 60 30];
            editfield2.Position = [300 100 60 30];

            %[app.CMinEditField.Value app.CMaxEditField.Value]
            if mode == 1

                if isempty(app.ExpStruct(exp).exp(subfield).roi) == 0
                    interactivefig.AlphaData = ~any(app.ExpStruct(exp).exp(subfield).roi,3);  % mask all the cells that were already masked and show the rest of the tissue
                end

                %draw multiple rois
                newmask = zeros(app.sz(1),app.sz(2),2);
                
                again = true;
                regionCount = 1;
                while again && regionCount < 2000

                	promptMessage = sprintf('Draw region #%d in the upper right image,\nor Quit?', regionCount);
                	titleBarCaption = 'Continue?';
                	button = questdlg(promptMessage, titleBarCaption, 'Draw', 'Quit', 'Draw');
                	if strcmpi(button, 'Quit')
                		break;
                	end

                    roi=drawfreehand(ax,'LineWidth',1);
                    
                    newmask(:,:,regionCount) = createMask(roi,app.singlemask);

                	regionCount = regionCount + 1;                   
  
                end

                if exist('roi', 'var') == 1
                    delete(roi)
                end 
                
                %newmask = logical(newmask);
                if regionCount == 2
                    newmask = newmask(:,:,1);
                end
                
                if isempty(app.ExpStruct(exp).exp(subfield).roi)
                    app.ExpStruct(exp).exp(subfield).roi = zeros(size(newmask,1),size(newmask,2),size(newmask,3));
                    app.ExpStruct(exp).exp(subfield).roi = newmask;   
                else
                    app.ExpStruct(exp).exp(subfield).roi = cat(3, app.ExpStruct(exp).exp(subfield).roi, newmask);
                end
                
                % execute the function to extract the timecourse
                Mask_TimeCourse_Centroid_Extractor(app,exp,subfield)

                app.NofROIsinthisfield.Value = size(app.ExpStruct(exp).exp(subfield).roi,3);

            elseif mode == 2 % background when you are in the first mode

                app.Lamp_3.Color = 'red';

                %draw multiple rois
                newmask = zeros(app.sz(1),app.sz(2));

                roi=drawfreehand(ax,'LineWidth',1);
                    
                newmask = createMask(roi,app.singlemask);

                newmask = logical(newmask);

                if exist('roi', 'var') == 1
                    delete(roi)
                end  
                
                app.ExpStruct(exp).exp(subfield).background = zeros(1,size(app.ExpStruct(exp).exp(subfield).subfield(:,:,:),3));
                for j = 1:size(app.ExpStruct(exp).exp(subfield).subfield(:,:,:),3)
                    stats = regionprops(newmask,app.ExpStruct(exp).exp(subfield).subfield(:,:,j),  'MeanIntensity');
                    app.ExpStruct(exp).exp(subfield).background(1,j) = stats.MeanIntensity;
                end

                app.Lamp_3.Color = 'green';

            % elimination of masks
            elseif mode == 3
                interactivefig.AlphaData = any(app.ExpStruct(exp).exp(subfield).roi,3); % show me the masks, so I can choose which one has to be eliminated
                %draw circles on the rois that you want to eliminate
                newmask = zeros(app.sz(1),app.sz(2),2);
                again = true;
                regionCount = 1;
                while again && regionCount < 2000

                    promptMessage = sprintf('Draw region #%d in the upper right image,\nor Quit?', regionCount);
                    titleBarCaption = 'Continue?';
                    button = questdlg(promptMessage, titleBarCaption, 'Draw', 'Quit', 'Draw');
                    if strcmpi(button, 'Quit')
                        break;
                    end

                    roi=drawcircle(ax);

                    newmask(:,:,regionCount) = createMask(roi,app.singlemask);

                    regionCount = regionCount + 1;

                    if regionCount == 2
                        newmask = newmask(:,:,1);
                    end

                end   

                %eliminate the mask that is covered by your roi
                newmask = logical(newmask);
                for i = 1:size(newmask,3)
                    el = any(newmask(:,:,i) & app.ExpStruct(exp).exp(subfield).roi, [1 2]);
                    app.ExpStruct(exp).exp(subfield).roi(:,:,el) = [];
                end

                % execute the function to extract the timecourse
                Mask_TimeCourse_Centroid_Extractor(app,exp,subfield)
                

                app.NofROIsinthisfield.Value = size(app.ExpStruct(exp).exp(subfield).roi,3);

            end

            % show again the gui
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            % delete roi and close your interactive figure
            if exist('roi', 'var') == 1
                delete(roi)
            end
            delete(interactivefig)
            delete(figure(1))
            delete(fig2)
            delete(fig3)


            % display the masks if you created some, display the legend,
            % initialize the structure upstates (will contain the
            % upstates of each roi, if oscillation mode is used)
            if isempty(app.ExpStruct(exp).exp(subfield).roi) == 0 

                % creating and plotting the transparent masks
                twoDimTransparencyComp(app,app.ExpStruct(exp).exp(subfield).roi)

                %legend 
                Legend(app,app.legendAxes,app.ExpStruct(exp).exp(subfield).roi,app.C)


            else
                app.chMasks.CData = [];
                cla(app.legendAxes)
            end

        end

        function Mask_TimeCourse_Centroid_Extractor(app,exp,subfield)

            % compute the timecorse of the average of the mask pixels and
            % the centroid
            app.ExpStruct(exp).exp(subfield).maskstimecourse = zeros(size(app.ExpStruct(exp).exp(subfield).roi,3),size(app.ExpStruct(exp).exp(subfield).subfield(:,:,:),3));
            app.ExpStruct(exp).exp(subfield).centroid = zeros(2,size(app.ExpStruct(exp).exp(subfield).roi,3));
            for i = 1:size(app.ExpStruct(exp).exp(subfield).roi,3)
                for j = 1:size(app.ExpStruct(exp).exp(subfield).subfield,3)
                    stats = regionprops(app.ExpStruct(exp).exp(subfield).roi(:,:,i),app.ExpStruct(exp).exp(subfield).subfield(:,:,j), 'MeanIntensity');
                    app.ExpStruct(exp).exp(subfield).maskstimecourse(i,j) = stats.MeanIntensity;
                end

                centr = regionprops(app.ExpStruct(exp).exp(subfield).roi(:,:,i), 'Centroid');
                app.ExpStruct(exp).exp(subfield).centroid(:,i) = centr.Centroid';

            end

        end

        function Binarizer(app,exp,subfield)  


            if app.GlobalButton.Value
                thr = app.ThreshSlider.Value;
                binfig = imbinarize(app.der_zproj,thr);
            else
                sens = app.ThreshSlider.Value;
                % rescale and apply a gamma
                tempimg = rescale(app.der_zproj,0,1).^0.55;
                if mod(app.nhood.Value,2)==0
                    app.nhood.Value = app.nhood.Value-1;
                end
                binfig = imbinarize(tempimg,adaptthresh(tempimg,sens,'NeighborhoodSize',app.nhood.Value,'Statistic','gaussian'));

            end

            if app.Erode.Value>0
                erode_strel=strel("disk",app.Erode.Value,4);
                binfig = imerode(binfig,erode_strel);
            end
            if app.Dilate.Value>0
                dilate_strel=strel("disk",app.Dilate.Value,4);
                binfig = imdilate(binfig,dilate_strel);
            end

            % correct the binarized filling the holes
            binfig = bwmorph(binfig,'fill',2);
            
            % remove connected areas smaller than threshold
            bound = bwareaopen(binfig,app.MinAreaThr.Value);

            binfig = imfill(bound,'holes');

            app.binarized = binfig;

            % take the binarized image and create rois
            edge = bwboundaries(app.binarized);
            newmask = zeros(app.sz(1),app.sz(2),2);

            singl = app.singlemask;
            for i = 1:size(edge,1)

                roi=drawpolygon(app.chAxes,'Position',fliplr(edge{i}));

                newmask(:,:,i) = createMask(roi,singl);
    
                newmask(:,:,i) = logical(newmask(:,:,i));
    
                % roi = [];
                if exist('roi', 'var') == 1
                    delete(roi)
                end
 
            end

            if size(edge,1) == 1
                newmask = newmask(:,:,1);
            end

            app.ExpStruct(exp).exp(subfield).roi = zeros(size(newmask,1),size(newmask,2),size(newmask,3));
            app.ExpStruct(exp).exp(subfield).roi = newmask;                
            

        end

        function Plotter(app,mode,exp,subfield)

            app.freq = app.FPSEditFieldMain.Value;
            app.samplingperiod = 1/app.freq;
            app.time = 0:app.samplingperiod:(size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse,2)/app.freq)-app.samplingperiod;
            app.tstart = app.time(1);
            app.tend = app.time(end);


            if mode == 1 % compute the subtraction between timeseries and the background or the detrended 

 
                app.ExpStruct(exp).exp(subfield).subtracted = zeros(size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1),size(app.ExpStruct(exp).exp(subfield).maskstimecourse,2));
                if app.Detrendorder.Value == 0
                    app.ExpStruct(exp).exp(subfield).subtracted = app.ExpStruct(exp).exp(subfield).maskstimecourse; 
                else
                    for i = 1:size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1)
                        avg = mean(app.ExpStruct(exp).exp(subfield).maskstimecourse(i,:) ,'all');
                        app.ExpStruct(exp).exp(subfield).subtracted(i,:) = detrend(app.ExpStruct(exp).exp(subfield).maskstimecourse(i,:),app.Detrendorder.Value); %+ squeeze(median(app.ExpStruct(exp).exp(subfield).subfield, [1 2 3]));
                        app.ExpStruct(exp).exp(subfield).subtracted(i,:) = app.ExpStruct(exp).exp(subfield).subtracted(i,:) + avg;
                    end
                end

                cla(app.SubtractedTimeseries)                
                hold(app.SubtractedTimeseries, 'on')
                plot(app.SubtractedTimeseries, app.time, app.ExpStruct(exp).exp(subfield).subtracted')
                %xlim(app.SubtractedTimeseries,[app.tstart app.tend])
                xticks(app.SubtractedTimeseries,[app.tstart:10:app.tend])
                %legend(app.SubtractedTimeseries)

            elseif mode == 2 % compute the normalized timeseries

                app.ExpStruct(exp).exp(subfield).normalized = zeros(size(app.ExpStruct(exp).exp(subfield).subtracted,1),size(app.ExpStruct(exp).exp(subfield).maskstimecourse,2));

                app.ExpStruct(exp).exp(subfield).normalized = normalize(app.ExpStruct(exp).exp(subfield).subtracted,2,'zscore');
                  
                cla(app.NormalizedTimeseries)                
                hold(app.NormalizedTimeseries, 'on')
                plot(app.NormalizedTimeseries, app.time, app.ExpStruct(exp).exp(subfield).normalized')
                xticks(app.NormalizedTimeseries,[app.tstart:10:app.tend])
                
                % set the lines in the normalized plot
                %app.xmarker = xline(app.NormalizedTimeseries,10,'k');
                app.ymarker = yline(app.NormalizedTimeseries,app.Ythreshold.Value,'k');
                %app.Slider2.Value = 10;
            end
        end

        function ExtractMeanBaseline(app,exp,subfield) %needed to extract the mean baseline form the subtracted timeseries, that will be used for normalization
                app.ExpStruct(exp).exp(subfield).meanbackground_fromsubtractedtimeseries = mean(app.ExpStruct(exp).exp(subfield).subtracted(:,round(app.BaselineWindowStart.Value*app.FPSEditFieldMain.Value)+1:round(app.BaselineWindowEnd.Value*app.FPSEditFieldMain.Value)),2);
        end


        function DisplayResults(app,exp,subfield)
            

                % TIMESERIES TABLE                 

                %columns name
                numberofexp = 3* size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1); % nubmer of timeseries, n of subtracted, number of normalized and one background
                timeseriesname = (1:numberofexp);

                timeseriesname = arrayfun(@num2str,timeseriesname,'un',0);

                for i = 1:size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1)
                    timeseriesname(i) = regexprep(timeseriesname(i),'.',strcat('raw',num2str(i)));
                    timeseriesname(i + size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1)) = regexprep(timeseriesname(i + size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1)),'.',strcat('subtracted',num2str(i)));
                    timeseriesname(i + (size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1)*2)) = regexprep(timeseriesname(i + (size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1)*2)),'.',strcat('normalized',num2str(i)));
                    %timeseriesname(i + (size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1)*3) + 1) = regexprep(timeseriesname(i + (size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1)*3) + 1),'.',strcat('centroid',num2str(i)));

                end                    


                app.TimeseriesTable.ColumnName = timeseriesname;

                % data
                set(app.TimeseriesTable, 'Data', []);
                rep_centroids = [app.ExpStruct(exp).exp(subfield).centroid app.ExpStruct(exp).exp(subfield).centroid app.ExpStruct(exp).exp(subfield).centroid];
                empty = NaN(1,size(rep_centroids,2));
                dataset= [app.ExpStruct(exp).exp(subfield).maskstimecourse' app.ExpStruct(exp).exp(subfield).subtracted' app.ExpStruct(exp).exp(subfield).normalized' ];
                
                app.timeseriesdataset = cat(1,rep_centroids,empty,dataset);
                set(app.TimeseriesTable, 'Data', app.timeseriesdataset);


                % RESULTS TABLE


                % get current roi
                curROI = app.ShowROInorm.Value;

                % col names
                colnames = {'Start(s)', 'Peak(s)', 'End(s)', 'Duration(s)', 'peakampl(deltaF/F)', 'peakampl(zscore)',  'auc', 'avgrisingslope', 'maxrisingslope', 'avgfallingslope', 'maxfallingslope'};
                % convert index in time!!!
               
                app.ResultsTable.ColumnName = colnames;

                % data
                set(app.ResultsTable, 'Data', []);

                app.resultsdataset = [(app.ExpStruct(exp).exp(subfield).upstates(curROI).start.*app.samplingperiod)'...
                    (app.ExpStruct(exp).exp(subfield).upstates(curROI).peakindex.*app.samplingperiod)...
                    (app.ExpStruct(exp).exp(subfield).upstates(curROI).end.*app.samplingperiod)'...
                    app.ExpStruct(exp).exp(subfield).upstates(curROI).duration'...
                    app.ExpStruct(exp).exp(subfield).upstates(curROI).peakampl'...
                    app.ExpStruct(exp).exp(subfield).upstates(curROI).peakamplzscore'...
                    app.ExpStruct(exp).exp(subfield).upstates(curROI).auc'...
                    app.ExpStruct(exp).exp(subfield).upstates(curROI).meanrisingslope'...
                    app.ExpStruct(exp).exp(subfield).upstates(curROI).maxrisingslope'...
                    app.ExpStruct(exp).exp(subfield).upstates(curROI).meanfallingslope'...
                    app.ExpStruct(exp).exp(subfield).upstates(curROI).maxfallingslope'];

                set(app.ResultsTable, 'Data', app.resultsdataset); 

                
            end


        % functions to copy the tables
        
        function tabletoarray(app,channel)
            % To copy the data from the table to the clipboard
            if channel == 1
                data = num2cell(app.timeseriesdataset);
            elseif channel == 2
                data = num2cell(app.resultsdataset);
            end

            tablesize = size(data);

            str = '';
            for i = 1:tablesize(1)
                for j = 1:tablesize(2)
                    switch j
                        case 1
                            str = sprintf('%s%i\t',str,data{i,j});
                            
                        case tablesize(2)
                            str = sprintf('%s%f',str,data{i,j});
                            
                        otherwise
                            str = sprintf('%s%f\t',str,data{i,j});
                            
                    end
                   
                end
                str = sprintf('%s\n',str);
            end
            clipboard ('copy',str);
            
            mymat = data;
            mymat(:,1) = cellfun(@double,mymat(:,1),'uniformoutput',false);
            array = cell2mat(mymat);
            num2clip(app,array);
        end

        % function to copy the data as table
        function arraystring = num2clip(~,array)
            %NUM2CLIP copies a numerical-array to the clipboard
            %
            %   ARRAYSTRING = NUM2CLIP(ARRAY)
            %
            %   Copies the numerical array ARRAY to the clipboard as a tab-separated
            %   string.  This format is suitable for direct pasting to Excel and other
            %   programs.
            %
            %   The tab-separated result is returned as ARRAYSTRING.  This
            %   functionality has been included for completeness.
            %
            %Author: Grigor Browning
            %Last update: 02-Sept-2005
            
            %convert the numerical array to a string array
            %note that num2str pads the output array with space characters to account
            %for differing numbers of digits in each index entry
            arraystring = num2str(array);
            arraystring(:,end+1) = newline; %add a carrige return to the end of each row
            %reshape the array to a single line
            %note that the reshape function reshape is column based so to reshape by
            %rows one must use the inverse of the matrix
            arraystring = reshape(arraystring',1,numel(arraystring)); %reshape the array to a single line
            
            arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
            arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
            
            %now remove the additional space charaters - keeping a single space
            %charater after each 'numerical' entry
            arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
            
            arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
            
            clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
        end   

        function Filter(app,low,high,filterorder)

            if low == 0 % lower bound = 0 means low-pass
                [app.b,app.a] = butter(filterorder,2*high*app.samplingperiod,'low');  %Low pass filter
            elseif high == Inf % upper bound = inf means high-pass
                [app.b,app.a] = butter(filterorder,2*low*app.samplingperiod, 'high');% high pass and single units
            else % band-pass
                frequencyBand = [low high];
                [app.b,app.a] = butter(filterorder,2*frequencyBand*app.samplingperiod);
            end                
        end

        function twoDimTransparencyComp(app,mat)
                %color
                app.C = turbo(size(mat,3));
                %C = C(randperm(size(C,1)),:);

                %initialize the transparency component
                alpha = any(mat,3) .* 0.4;

                % create an empty image, if it has already the correct shape
                % don't reinitialize it
                rainbow = zeros(app.sz(1),app.sz(2),3);

                % assign a different color for every mask to the empty image
                for i=1:size(mat,3)
                    tmp=repmat(mat(:,:,i),1,1,3).*reshape(app.C(i,:),1,1,3);
                    rainbow=rainbow+tmp;
                end

                app.chMasks.CData = rainbow(:,:,:);
                app.chMasks.AlphaData = alpha;

        end

        function Legend(app,ax,mat,C)
            %legend 
            cla(ax)
            labelsArr = cell(1, size(mat,3));
            for ii = 1:length(labelsArr)
                labelsArr{ii} = [num2str(ii)];
            end
            hold(ax, 'on')
            for ii = 1:size(mat,3)
                % Make a new legend entry for each label. 'color' contains a 0->255 RGB triplet
                scatter(ax,[],[],1, C(ii,:), 'filled', 'DisplayName', labelsArr{ii});
            end
            hold(ax, 'off')
            legend(ax);            
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize images
            app.chImg = matlab.graphics.primitive.Image.empty(2,0);
            app.chImg = imagesc(app.greench,nan);

            % hold axes
            hold(app.greench, 'on')

            app.chMasks = matlab.graphics.primitive.Image.empty(2,0);
            app.chMasks = imagesc(app.greench,nan);  

            app.legendimg = matlab.graphics.primitive.Image.empty(2,0);
            app.legendimg = imagesc(app.legendAxes,nan); 

            %ratio
            daspect(app.greench, [1 1 1])  

            % group controllers
            app.chAxes = app.greench;
            app.cmin = app.CMinEditField;
            app.cmax = app.CMaxEditField;
            app.histoaxes = app.greenHistoAxes;


        end

        % Button pushed function: SelectfolderandfileButton
        function SelectfolderandfileButtonPushed(app, event)
            Files(app)
            Sorter(app)
        end

        % Value changed function: CMaxEditField, CMinEditField
        function greenCMinEditFieldValueChanged(app, event)
            app.greench.CLim = [app.CMinEditField.Value, app.CMaxEditField.Value];
            app.histoaxes.XLim = [app.CMinEditField.Value, app.CMaxEditField.Value];
        end

        % Button pushed function: DrawROIsButton
        function DrawROIsButtonPushed(app, event)
            MaskDrawer(app,1,str2double(app.ExperimentnumberDropDown.Value), str2double(app.FieldnumberDropDown.Value))
        end

        % Button pushed function: PlotDetrendedButton
        function PlotDetrendedButtonPushed(app, event)
            Plotter(app,1,str2double(app.ExperimentnumberDropDown.Value), str2double(app.FieldnumberDropDown.Value))
        end

        % Button pushed function: PlotNormalizedButton
        function PlotNormalizedButtonPushed(app, event)
            Plotter(app,2,str2double(app.ExperimentnumberDropDown.Value), str2double(app.FieldnumberDropDown.Value))
            app.ShowROInorm.Value = 1;
        end

        % Button pushed function: greenCopyTable
        function greenCopyTableButtonPushed(app, event)
            tabletoarray(app,1)
        end

        % Button pushed function: greenCopyTable_2
        function greenCopyTable_2ButtonPushed(app, event)
            tabletoarray(app,2)
        end

        % Value changed function: Datatype
        function DatatypeValueChanged(app, event)
            value = app.Datatype.Value;
            if strcmp(app.Datatype.Value,'TIF list') == 1
                app.NumberOfExp.Visible = 'on';
                app.HowmanyexpdidyoudoLabel.Visible ='on';
                app.filename.Visible = 'off';
                app.filenametext.Visible ='off';
            elseif strcmp(app.Datatype.Value,'multiTIF (one exp)') == 1 
                app.NumberOfExp.Visible ='off';
                app.HowmanyexpdidyoudoLabel.Visible ='off';
                app.filename.Visible = 'on';
                app.filenametext.Visible = 'on';    
            elseif strcmp(app.Datatype.Value,'multiTIF (multi exp)') == 1  
                app.NumberOfExp.Visible = 'on';
                app.HowmanyexpdidyoudoLabel.Visible ='on';
                app.filename.Visible ='off';
                app.filenametext.Visible ='off';                
            end

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);            
            value = app.Slider.Value;
            app.img = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subfield(:,:,round(value));            
            app.chImg.CData = app.img;     
         
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);             
            changingValue = event.Value;
            app.img = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subfield(:,:,round(changingValue));            
            app.chImg.CData = app.img;          
    
        end

        % Value changed function: Ythreshold
        function YthresholdValueChanged(app, event)
            app.ymarker = yline(app.NormalizedTimeseries,app.Ythreshold.Value,'k');
            app.ymarker.Value = app.Ythreshold.Value; 
        end

        % Value changed function: ShowROIsub
        function ShowROIsubValueChanged(app, event)
            val = app.ShowROIsub.Value;
            if app.ShowROIsub.Value > size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted,1)
               app.ShowROIsub.Value = size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted,1); 
               val = size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted,1); 
            elseif app.ShowROIsub.Value == 0
               app.ShowROIsub.Value = 1; 
               val = 1;
            end
            cla(app.SubtractedTimeseries)                
            hold(app.SubtractedTimeseries, 'on')
            plot(app.SubtractedTimeseries,app.time, app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted(val,:)')
            xlim(app.SubtractedTimeseries,[app.tstart app.tend])
            %legend(app.SubtractedTimeseries)            
        end

        % Value changed function: ShowROInorm
        function ShowROInormValueChanged(app, event)
            val = app.ShowROInorm.Value;
            if app.ShowROInorm.Value > size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted,1)
               app.ShowROInorm.Value = size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted,1); 
               val = size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted,1);             
            elseif app.ShowROInorm.Value == 0
               app.ShowROInorm.Value = 1;
               val = 1;               
            end            
            cla(app.NormalizedTimeseries)                
            hold(app.NormalizedTimeseries, 'on')
            plot(app.NormalizedTimeseries,app.time, app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).normalized(val,:)')
            xlim(app.NormalizedTimeseries,[app.tstart app.tend])
            %legend(app.NormalizedTimeseries)      
            app.ymarker = yline(app.NormalizedTimeseries,app.Ythreshold.Value,'k');
            app.ymarker.Value = app.Ythreshold.Value;            
            cla(app.ResultAxes)                
            hold(app.ResultAxes, 'on')
            plot(app.ResultAxes,app.time, app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted(val,:)')
            xlim(app.ResultAxes,[app.tstart app.tend])
            
        end

        % Button pushed function: SavenormalizedButton
        function SavenormalizedButtonPushed(app, event)
            struct = reshape(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).normalized,[size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).normalized,1),1,size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).normalized,2)]);
            save(strjoin({'rois', app.filename.Value,'.mat'}),'struct') 
        end

        % Button pushed function: PlotRawButton
        function PlotRawButtonPushed(app, event)
            app.freq = app.FPSEditFieldMain.Value;
            app.samplingperiod = 1/app.freq;
            app.time = 0:app.samplingperiod:(size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse,2)/app.freq)-app.samplingperiod;
            app.tstart = app.time(1);
            app.tend = app.time(end);
            
            cla(app.RawTimeseries)
            hold(app.RawTimeseries, 'on')
            plot(app.RawTimeseries, app.time, app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse')
            %xlim(app.RawTimeseries,[app.tstart app.tend])
            xticks(app.SubtractedTimeseries,[app.tstart:10:app.tend])
            %legend(app.RawTimeseries)


            app.EndsEditField.Value = size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse,2)/app.freq;
        end

        % Button pushed function: CROPButton
        function CROPButtonPushed(app, event)
            app.temp = [];
            app.tempbkg = [];
            app.temp = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse;
            app.tempbkg = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).background;
            windstart = round(app.StartsEditField.Value*app.FPSEditFieldMain.Value);
            if windstart == 0
                windstart = windstart + 1;
            end

            %update freq and sp
            app.freq = app.FPSEditFieldMain.Value;
            app.samplingperiod = 1/app.freq;

            windend = round(app.EndsEditField.Value*app.FPSEditFieldMain.Value);
            app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse =  app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse(:,windstart:windend);
            if ~isempty(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).background)
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).background =  app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).background(1,windstart:windend);
            end
            %update time
            app.time = 0:app.samplingperiod:(size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse,2)/app.freq)-app.samplingperiod;
            app.tstart = app.time(1);
            app.tend = app.time(end);
           

            %plot again the raw data
            cla(app.RawTimeseries)
            hold(app.RawTimeseries, 'on')
            plot(app.RawTimeseries, app.time, app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse')
            %xlim(app.RawTimeseries,[app.tstart app.tend])
            xticks(app.SubtractedTimeseries,[app.tstart:10:app.tend])
            %legend(app.RawTimeseries)            
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            app.freq = app.FPSEditFieldMain.Value;
            app.samplingperiod = 1/app.freq;
            app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse = app.temp;  
            if ~isempty(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).background)
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).background = app.tempbkg;  
            end

            app.time = 0:app.samplingperiod:(size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse,2)/app.freq)-app.samplingperiod;
            app.tstart = app.time(1);
            app.tend = app.time(end); 
            %app.Slider2.Limits = [app.tstart app.tend];

            %plot again the raw data
            cla(app.RawTimeseries)
            hold(app.RawTimeseries, 'on')
            plot(app.RawTimeseries, app.time, app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse')
            %xlim(app.RawTimeseries,[app.tstart app.tend])
            xticks(app.SubtractedTimeseries,[app.tstart:10:app.tend])
            %legend(app.RawTimeseries)                   
        end

        % Button pushed function: ValidateOsc
        function ValidateOscButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            field = str2double(app.FieldnumberDropDown.Value);

            % initialize structure upstates
            app.ExpStruct(exp).exp(field).upstates = ...
                struct('peakindex', cell(size(app.ExpStruct(exp).exp(field).roi,3),1),...
                'peakampl', cell(size(app.ExpStruct(exp).exp(field).roi,3),1),...
                'peakamplzscore', cell(size(app.ExpStruct(exp).exp(field).roi,3),1),......
                'meanrisingslope',cell(size(app.ExpStruct(exp).exp(field).roi,3),1),...
                'maxrisingslope',cell(size(app.ExpStruct(exp).exp(field).roi,3),1),...
                'meanfallingslope',cell(size(app.ExpStruct(exp).exp(field).roi,3),1),...
                'maxfallingslope',cell(size(app.ExpStruct(exp).exp(field).roi,3),1),...
                'auc',cell(size(app.ExpStruct(exp).exp(field).roi,3),1),...
                'duration',cell(size(app.ExpStruct(exp).exp(field).roi,3),1),...
                'start',cell(size(app.ExpStruct(exp).exp(field).roi,3),1),...
                'end',cell(size(app.ExpStruct(exp).exp(field).roi,3),1));        

            
            curROI = app.ShowROInorm.Value;
            threshold = app.Ythreshold.Value;
            
            % set the current detrended and normalized traces
            curraw = app.ExpStruct(exp).exp(field).maskstimecourse(curROI,:); 
            curdetr = app.ExpStruct(exp).exp(field).subtracted(curROI,:); 
            curnorm = app.ExpStruct(exp).exp(field).normalized(curROI,:); 
            
            %automatic detection of upstates, we will do it on the
            %detrended+1 timeseries (named subtracted)
            %d1 = designfilt("lowpassiir",FilterOrder=12,HalfPowerFrequency=app.Low2.Value,DesignMethod="butter");
            Filter(app,app.Low2.Value,app.High2.Value,app.FilterOrder2.Value)
            y = filtfilt(app.b,app.a,curdetr);
            ynorm = filtfilt(app.b,app.a,curnorm); % compute also on the normalized because you will need the findpeaks with the threshold on it

            if strcmp(app.ProminenceSwitch.Value,'Threshold')
                [pks,curpeaks,w,p] = findpeaks(ynorm,app.FPSEditFieldMain.Value,"MinPeakDistance",app.MinDistance.Value);
                curpeaks = curpeaks(pks > threshold);
            else
                [pks,curpeaks,w,p] = findpeaks(ynorm,app.FPSEditFieldMain.Value,"MinPeakProminence",app.Ythreshold.Value,'MinPeakDistance',app.MinDistance.Value);
            end
            curpeaks = round(curpeaks.*app.FPSEditFieldMain.Value);
            app.ExpStruct(exp).exp(field).upstates(curROI).peakindex = curpeaks';
               
            % initialize an array that will mark bad peaks  
            bad = zeros(size(curpeaks,2),1);
            
            if isempty(app.ExpStruct(exp).exp(field).upstates(curROI).peakindex) == 0
                for i = 1:size(app.ExpStruct(exp).exp(field).upstates(curROI).peakindex,1)

                    %find indexes of the minima before the current peak
                    localstartidx = islocalmin(y(1,1:app.ExpStruct(exp).exp(field).upstates(curROI).peakindex(i)));

                    if app.ExpStruct(exp).exp(field).upstates(curROI).peakindex(i) == 1 || isempty(localstartidx) || app.ExpStruct(exp).exp(field).upstates(curROI).peakindex(i) == size(curdetr,2)
                        bad(i) = 1;
                    else
                        bad(i) = 0;

                        %find indexes of the minima before the current peak
                        localstartidx = islocalmin(y(1,1:app.ExpStruct(exp).exp(field).upstates(curROI).peakindex(i)));
                        % take only the last minumum before the peak
                        localstart = [];
                        if all(localstartidx == 0)
                            localstart = 1;
                        else
                            localstart = find(localstartidx);
                        end
                        app.ExpStruct(exp).exp(field).upstates(curROI).start(i) = localstart(end);

                        %find indexes of the minima
                        localendidx = islocalmin(y(1,:));
                        % take only the first minumum after the peak
                        localend = [];
                        localend = find(localendidx);
                        if localend(end) < curpeaks(:,i)
                            localendcorr = size(curdetr,2);
                        else
                            localendcorr = localend(localend > curpeaks(:,i));
                        end

                        app.ExpStruct(exp).exp(field).upstates(curROI).end(i) = localendcorr(1);


                        % cambia calcolo di ampl per picchi che sono ad inizio
                        % o fine, puoi prendere la media di alcuni punti interni al
                        % picco!! se e proprio alla fine infatti non puo
                        % prendere neanche un punto, quindi meglio di nulla


                        %calc peak ampl
                        %P = prctile(curdetr,[15 85], 'all'); % find the 15 and 85 percentiles
                        %freqval = curdetr(curdetr>P(1) & curdetr<P(2)); % % find values that are included in the 15-85 percentile
                        %meanfreq = mean(freqval);
                        avgwindow = app.AvgFwindow.Value/app.samplingperiod;
                        
                        if (localstart(end)-avgwindow) > 0 && (localendcorr(1)+avgwindow) < size(curdetr,2)
                            firstavg = mean(curdetr(1,(localstart(end)-avgwindow):localstart(end)));
                            secondavg = mean(curdetr(1,(localendcorr(1)):(localendcorr(1)+avgwindow)));
                        elseif (localstart(end)-avgwindow) <= 0
                            firstavg = mean(curdetr(1,1:localstart(end)));
                            secondavg = mean(curdetr(1,(localendcorr(1)):(localendcorr(1)+avgwindow)));
                            warning('The first peak is near an edge, consider restricting the window for finding the deltaF/F')
                        elseif (localendcorr(1)+avgwindow) > size(curdetr,2)
                            firstavg = mean(curdetr(1,(localstart(end)-avgwindow):localstart(end)));
                            secondavg = mean(curdetr(1,(localendcorr(1)):size(curdetr,2)));
                            warning('The last peak is near an edge, consider restricting the window for finding the deltaF/F')
                        end

                        totavg = (firstavg+secondavg)/2;

                        app.ExpStruct(exp).exp(field).upstates(curROI).peakampl(i) =...
                            abs((curdetr(1,app.ExpStruct(exp).exp(field).upstates(curROI).peakindex(i))-totavg)/totavg);

                        app.ExpStruct(exp).exp(field).upstates(curROI).peakamplzscore(i) =...
                            curnorm(1,app.ExpStruct(exp).exp(field).upstates(curROI).peakindex(i));

                        %calculate slopes
                        risingslope = diff(curnorm(app.ExpStruct(exp).exp(field).upstates(curROI).start(i):...
                            app.ExpStruct(exp).exp(field).upstates(curROI).peakindex(i)))./app.samplingperiod;
                        avgrisingslope = mean(risingslope);
                        maxrisingslope = max(risingslope);
                        app.ExpStruct(exp).exp(field).upstates(curROI).meanrisingslope(i) = avgrisingslope;
                        app.ExpStruct(exp).exp(field).upstates(curROI).maxrisingslope(i) = maxrisingslope;

                        fallingslope = diff(curnorm(app.ExpStruct(exp).exp(field).upstates(curROI).peakindex(i):...
                            app.ExpStruct(exp).exp(field).upstates(curROI).end(i)))./app.samplingperiod;
                        avgfallingslope = mean(fallingslope);
                        maxfallingslope = min(fallingslope,[],'all');
                        app.ExpStruct(exp).exp(field).upstates(curROI).meanfallingslope(i) = avgfallingslope;
                        app.ExpStruct(exp).exp(field).upstates(curROI).maxfallingslope(i) = maxfallingslope;

                        %auc
                        New_x = linspace(app.ExpStruct(exp).exp(field).upstates(curROI).start(i),app.ExpStruct(exp).exp(field).upstates(curROI).end(i),app.ExpStruct(exp).exp(field).upstates(curROI).end(i)-app.ExpStruct(exp).exp(field).upstates(curROI).start(i)); % Includes given points
                        New_y = linspace(curnorm(app.ExpStruct(exp).exp(field).upstates(curROI).start(i)),curnorm(app.ExpStruct(exp).exp(field).upstates(curROI).end(i)),app.ExpStruct(exp).exp(field).upstates(curROI).end(i)-app.ExpStruct(exp).exp(field).upstates(curROI).start(i)); % Includes given points 
                        area = trapz(New_x, abs(curnorm(app.ExpStruct(exp).exp(field).upstates(curROI).start(i):app.ExpStruct(exp).exp(field).upstates(curROI).end(i)-1) - New_y));

                        app.ExpStruct(exp).exp(field).upstates(curROI).auc(i) = area;
                        %trapz(curdetr(1,app.ExpStruct(exp).exp(field).upstates(curROI).start(i):app.ExpStruct(exp).exp(field).upstates(curROI).end(i)));

                        %duration
                        app.ExpStruct(exp).exp(field).upstates(curROI).duration(i) = ...
                            (app.time(app.ExpStruct(exp).exp(field).upstates(curROI).end(i))-app.time(app.ExpStruct(exp).exp(field).upstates(curROI).start(i)));
                    end

                end

                app.ExpStruct(exp).exp(field).upstates(curROI).peakindex(bad==1) = [];

                % plot as red the upstates
                cla(app.ResultAxes)
                plot(app.ResultAxes,app.time, app.ExpStruct(exp).exp(field).subtracted(curROI,:)')

                %if ~isempty(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex)
                for i = 1:size(app.ExpStruct(exp).exp(field).upstates(curROI).peakindex,1)
                    plot(app.ResultAxes, app.time(app.ExpStruct(exp).exp(field).upstates(curROI).start(i):app.ExpStruct(exp).exp(field).upstates(curROI).end(i)), ...
                        curdetr(1,app.ExpStruct(exp).exp(field).upstates(curROI).start(i):app.ExpStruct(exp).exp(field).upstates(curROI).end(i))','Color','red')
                end
            else
                                
                app.ExpStruct(exp).exp(field).upstates(curROI).peakindex = [];
                warning('No peaks detected, retry modifying the zscore threhold or the lowpass filter')
            end
            %xlim(app.NormalizedTimeseries,[app.tstart app.tend])
            %xticks(app.NormalizedTimeseries,0:size(app.ExpStruct(exp).exp(subfield).subfield(:,:,:),3))
            %legend(app.NormalizedTimeseries)
              
            %app.autopeaks = size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex, 1);

            DisplayResults(app,exp, str2double(app.FieldnumberDropDown.Value))

        end

        % Button pushed function: SeeButton
        function SeeButtonPushed(app, event)
            curROI = app.ShowROInorm.Value;
            %d1 = designfilt("lowpassiir",FilterOrder=12,HalfPowerFrequency=app.Low2.Value,DesignMethod="butter");
            Filter(app,app.Low2.Value,app.High2.Value,app.FilterOrder2.Value)
            y = filtfilt(app.b,app.a,app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted(curROI,:));
            figure(1)
            plot(app.time,y,app.time,app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted(curROI,:))
        end

        % Button pushed function: ValidateWindow
        function ValidateWindowButtonPushed(app, event)
            % design a manual upstate and add it to the other one
            curROI = app.ShowROInorm.Value;
            curraw = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).maskstimecourse(curROI,:); 
            curdetr = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted(curROI,:); 
            curnorm = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).normalized(curROI,:); 
            
            %d1 = designfilt("lowpassiir",FilterOrder=12,HalfPowerFrequency=app.Low2.Value,DesignMethod="butter");
            Filter(app,app.Low2.Value,app.High2.Value,app.FilterOrder2.Value)
            y = filtfilt(app.b,app.a,curdetr); 
            ynorm = filtfilt(app.b,app.a,curnorm);


            %peaks already present
            autopks = size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex,1);

            %get the commanded start and end
            app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).start(1+autopks) = round(app.OscStart.Value*app.freq);
            app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).end(1+autopks) = round(app.OscEnd.Value*app.freq);
            
            tempstart = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).start(1+autopks);
            tempend = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).end(1+autopks);
            
            % find the peak in the filetered trace
            [pks, allpeak] = findpeaks(ynorm,app.FPSEditFieldMain.Value,"MinPeakDistance",app.MinDistance.Value);  
            allpeak = (round(allpeak.*app.FPSEditFieldMain.Value))';
            %allpeak = allpeak';

            
            %take only the peak between the start and end
            goodpeak = [];
            goodpeak = allpeak(allpeak > round(app.OscStart.Value*app.freq) & allpeak < round(app.OscEnd.Value*app.freq));
            
            if size(goodpeak,2) > 1 || isempty(goodpeak)
                warning('Found more than one peak or zero peak, canceling the start and the end that you provided. Try again filtering with a different lowpass or changing the selection.')
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).start(1+autopks) = [];
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).end(1+autopks) = [];                
            elseif size(goodpeak,2) == 1 
                % assign to the structure
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex(1+autopks) = goodpeak';

                %calc peak amplitude
                % P = prctile(curdetr,[15 85], 'all'); % find the 15 and 85 percentiles
                % freqval = curdetr(curdetr>P(1) & curdetr<P(2)); % % find values that are included in the 15-85 percentile
                % meanfreq = mean(freqval);
                % app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakampl(1+autopks) =...
                %     abs((curdetr(1,goodpeak)-meanfreq)/meanfreq);

                
                avgwindow = app.AvgFwindow.Value/app.samplingperiod;
                if (tempstart-avgwindow) > 0 && (tempend+avgwindow) <= size(curdetr,2)
                    firstavg = mean(curdetr(1,(tempstart-avgwindow):tempstart));
                    secondavg = mean(curdetr(1,(tempend):(tempend+avgwindow)));
                elseif (tempstart-avgwindow) <= 0
                    firstavg = mean(curdetr(1,1:tempstart));
                    secondavg = mean(curdetr(1,(tempend):(tempend+avgwindow)));
                    warning('The first peak is near the start of the recording, consider restricting the window to find the deltaF/F')
                elseif (tempend+avgwindow) > size(curdetr,2)
                    firstavg = mean(curdetr(1,(tempstart-avgwindow):tempstart));
                    secondavg = mean(curdetr(1,(tempend):size(curdetr,2)));
                    warning('The last peak is near the end of the recording, consider restricting the window to find the deltaF/F')
                end

                totavg = (firstavg+secondavg)/2;

                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakampl(1+autopks) =...
                    abs((curdetr(1,app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex(1+autopks))-totavg)/totavg);

                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakamplzscore(1+autopks) =...
                    curnorm(1,app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex(1+autopks));

                %compute the slopes and assign
                risingslope = diff(curnorm(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).start(1+autopks):...
                    app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex(1+autopks)))./app.samplingperiod;
                avgrisingslope = mean(risingslope);
                maxrisingslope = max(risingslope);
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).meanrisingslope(1+autopks) = avgrisingslope;
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).maxrisingslope(1+autopks) = maxrisingslope;

                fallingslope = diff(curnorm(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex(1+autopks):...
                    app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).end(1+autopks)))./app.samplingperiod;
                avgfallingslope = mean(fallingslope);
                maxfallingslope = min(fallingslope);
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).meanfallingslope(1+autopks) = avgfallingslope;
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).maxfallingslope(1+autopks) = maxfallingslope;
                
                %auc
                New_x = linspace(app.ExpStruct(exp).exp(field).upstates(curROI).start(i),app.ExpStruct(exp).exp(field).upstates(curROI).end(i),app.ExpStruct(exp).exp(field).upstates(curROI).end(i)-app.ExpStruct(exp).exp(field).upstates(curROI).start(i)); % Includes given points
                New_y = linspace(curnorm(app.ExpStruct(exp).exp(field).upstates(curROI).start(i)),curnorm(app.ExpStruct(exp).exp(field).upstates(curROI).end(i)),app.ExpStruct(exp).exp(field).upstates(curROI).end(i)-app.ExpStruct(exp).exp(field).upstates(curROI).start(i)); % Includes given points 
                area = trapz(New_x, abs(curnorm(app.ExpStruct(exp).exp(field).upstates(curROI).start(i):app.ExpStruct(exp).exp(field).upstates(curROI).end(i)-1) - New_y));

                app.ExpStruct(exp).exp(field).upstates(curROI).auc(i) = area;
                %trapz(curdetr(1,app.ExpStruct(exp).exp(field).upstates(curROI).start(i):app.ExpStruct(exp).exp(field).upstates(curROI).end(i)));
                
                %duration
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).duration(1+autopks) = ...
                    app.time(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).end(1+autopks))-app.time(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).start(1+autopks));
            
            % fai in modo che se c'e un altro upstate con lo xtesso picco
            % create in automatico quello venga cancellato ed aggiornato
            % con quello fatto a mano
            
            end

            % adapting it to make it compatible for the printing
            if size( app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex,2) >  size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex,1)
                app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex = app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex';
            end

            cla(app.ResultAxes)
            plot(app.ResultAxes,app.time, app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).subtracted(curROI,:)')
            
            if ~isempty(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex)
                for i = 1:size(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).peakindex,1)
                    plot(app.ResultAxes, app.time(app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).start(i):app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).end(i)), ...
                        curdetr(1,app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).start(i):app.ExpStruct(str2double(app.ExperimentnumberDropDown.Value)).exp(str2double(app.FieldnumberDropDown.Value)).upstates(curROI).end(i))','Color','red')
                end
            end
           
            DisplayResults(app,str2double(app.ExperimentnumberDropDown.Value), str2double(app.FieldnumberDropDown.Value))
        end

        % Button pushed function: greenDeleteROIsButton
        function greenDeleteROIsButtonPushed(app, event)
           MaskDrawer(app,3,str2double(app.ExperimentnumberDropDown.Value), str2double(app.FieldnumberDropDown.Value)) 
        end

        % Button pushed function: LowpassanddifferentialButton
        function LowpassanddifferentialButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);            
            
            
            app.LampFilterMain.Color = 'red';

            %d1 = designfilt("lowpassiir",FilterOrder=12,HalfPowerFrequency=app.Low1.Value,DesignMethod="butter");
            Filter(app,app.Low1.Value,app.High1.Value,app.FilterOrder.Value) 

            lowvid = zeros(app.sz(1)*app.sz(2),app.sz(3));
            vidresh = reshape(app.ExpStruct(exp).exp(subfield).subfield, [], app.sz(3)); 
            tempb = app.b;
            tempa = app.a;
            parfor i = 1:size(vidresh,1) %parfor
                    lowvid(i,:) = filtfilt(tempb,tempa,vidresh(i,:));
            end

            der = zeros(app.sz(1)*app.sz(2),app.sz(3)-1);
            %der = reshape(der, [], app.sz(3)-1);
            parfor i = 1:size(vidresh,1) %parfor
                    der(i,:) = diff(lowvid(i,:));
            end

            lowvid =  reshape(lowvid, app.sz(1), app.sz(2), app.sz(3));
            % save it only if you are going to use the dynamic ROIs, to
            % save space
            app.lowvideo = [];

            der = reshape(der, app.sz(1), app.sz(2), app.sz(3)-1);
            % all elements under zero as zero
            der(der<0) = 0;

            % don't save it if you are going to use the dynamic ROIs, to
            % save space
            app.der_zproj = [];
            app.der_zproj = sum(der,3);

            app.LampFilterMain.Color = 'green';

        end

        % Button pushed function: SeediffButton
        function SeediffButtonPushed(app, event)
            figure(1)
            imagesc(app.der_zproj)
        end

        % Button pushed function: autorois
        function autoroisButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);

            % launch the function to find the binarized mask
            Binarizer(app,exp,subfield)
            
            % execute the function to extract the timecourse
            Mask_TimeCourse_Centroid_Extractor(app,exp,subfield)

            % creating and plotting transparent masks
            twoDimTransparencyComp(app,app.ExpStruct(exp).exp(subfield).roi)

            %legend
            Legend(app,app.legendAxes,app.ExpStruct(exp).exp(subfield).roi,app.C)

            % modify the displayed number of rois
            app.NofROIsinthisfield.Value = size(app.ExpStruct(exp).exp(subfield).roi,3);

  
            % initialize structure upstates
            app.ExpStruct(exp).exp(subfield).upstates = ...
                struct('peakindex', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'peakampl', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'peakamplzscore', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),......
                'meanrisingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'maxrisingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'meanfallingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'maxfallingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'auc',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'duration',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'start',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'end',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1));
        end

        % Button pushed function: SeeBinarizedButton
        function SeeBinarizedButtonPushed2(app, event)
            function Update(src,event,interactivefig)
                val = event.Value;
                app.ThreshSlider.Value = val;
                SmallBinarizer(app)
                interactivefig = imagesc(ax,app.binarized);
            end                   

            function SmallBinarizer(app)
                if app.GlobalButton.Value
                    thr = app.ThreshSlider.Value;
                    binfig = imbinarize(app.der_zproj,thr);
                else
                    sens = app.ThreshSlider.Value;
                    % rescale and apply a gamma
                    tempimg = rescale(app.der_zproj,0,1).^0.55;
                    if mod(app.nhood.Value,2)==0
                        app.nhood.Value = app.nhood.Value-1;
                    end
                    binfig = imbinarize(tempimg,adaptthresh(tempimg,sens,'NeighborhoodSize',app.nhood.Value,'Statistic','gaussian'));
    
                end
    
                if app.Erode.Value>0
                    erode_strel=strel("disk",app.Erode.Value,4);
                    binfig = imerode(binfig,erode_strel);
                end
                if app.Dilate.Value>0
                    dilate_strel=strel("disk",app.Dilate.Value,4);
                    binfig = imdilate(binfig,dilate_strel);
                end   
                % correct the binarized filling the holes
                binfig = bwmorph(binfig,'fill',2);              
                % remove connected areas smaller than threshold
                bound = bwareaopen(binfig,app.MinAreaThr.Value); 
                binfig = imfill(bound,'holes');
                app.binarized = binfig;
            end

            SmallBinarizer(app)

            fig = figure(2);
            ax = axes(fig);
            interactivefig = imagesc(ax,app.binarized);

            fig2 = uifigure();
            fig2.Units = 'Normalized';
            fig2.Position = [0.7, 0.5, 0.3, 0.3];
            
            sld = uislider(fig2,"Value",1,"ValueChangingFcn",@(src,event)Update(src,event,interactivefig));
            sld.Limits = [app.first app.last]; 
        end

        % Selection changed function: greenThrButtonGroup
        function greenThrButtonGroupSelectionChanged(app, event)
            selectedButton = app.greenThrButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Global'
                    app.first = min(app.der_zproj,[],'all');
                    app.last = max(app.der_zproj,[],'all');
                    app.ThreshSlider.Limits = [app.first app.last];
                case 'Adaptive'
                    % [app.first app.last]=evaluateSens(app.der_zproj);
                    app.first = 0;
                    app.last = 1;
                    app.ThreshSlider.Limits = [app.first app.last];
            end
        end

        % Value changed function: ThreshSlider
        function ThreshSliderValueChanged(app, event)
            
        end

        % Value changing function: ThreshSlider
        function ThreshSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: SeezprojButton
        function SeezprojButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);

            avg = zeros(app.sz(3),1);
            for i=1:app.sz(3)
                avg(i) = mean(app.ExpStruct(exp).exp(subfield).subfield(:,:,i),'all');
            end
            figure(1)
            plot(avg)
            %ylim([min(avg,[],'all') max(avg,[],'all')])
        end

        % Button pushed function: DetrendingButton
        function DetrendingButtonPushed(app, event)
            app.LampSubAvg.Color = 'red';            
            
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);  
            ord = app.DetrendOrdMain.Value;
            
            % avg = zeros(app.sz(3));
            % for i=1:app.sz(3)
            %     avg(i) = mean(app.ExpStruct(exp).exp(subfield).subfield(:,:,i), 'all');
            % end
            % totavg = mean(avg,'all');
            % 
            % for i=1:app.sz(3)
            %     app.ExpStruct(exp).exp(subfield).subfield(:,:,i) = (app.ExpStruct(exp).exp(subfield).subfield(:,:,i) - avg(i)) + totavg;
            % end

            tempresh = reshape(app.ExpStruct(exp).exp(subfield).subfield,[],size(app.ExpStruct(exp).exp(subfield).subfield,3));
            parfor i = 1:size(tempresh,1)
                avg = mean(tempresh(i,:),'all');
                tempresh(i,:) = detrend(tempresh(i,:),ord);
                tempresh(i,:) = tempresh(i,:) + avg;
            end
            deresh = reshape(tempresh, app.sz(1), app.sz(2), app.sz(3));
            app.ExpStruct(exp).exp(subfield).subfield = deresh;
            
            app.LampSubAvg.Color = 'green'; 
        end

        % Button pushed function: RemoveallsubthrROIsButton
        function RemoveallsubthrROIsButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);

            el = zeros(size(app.ExpStruct(exp).exp(subfield).normalized,1),1);
            for i = 1:size(app.ExpStruct(exp).exp(subfield).roi,3)
                if max(app.ExpStruct(exp).exp(subfield).normalized(i,:),[],'all') < app.ThresholdROItimecourse.Value
                    el(i) = 1;
                end
            end
            app.ExpStruct(exp).exp(subfield).roi(:,:,logical(el)) = [];

            Mask_TimeCourse_Centroid_Extractor(app,exp,subfield)
            app.PlotRawButtonPushed(event)
            app.PlotDetrendedButtonPushed(event)
            app.PlotNormalizedButtonPushed(event)


            % creating and plotting transparent masks
            twoDimTransparencyComp(app,app.ExpStruct(exp).exp(subfield).roi)

            %legend
            Legend(app,app.legendAxes,app.ExpStruct(exp).exp(subfield).roi,app.C)


            % update the list of rois in the cross correlation and roi
            % selector next to plots
            roilist = (1:size(app.ExpStruct(exp).exp(subfield).roi,3));
            roilist = arrayfun(@num2str,roilist,'un',0);
            app.Data1ROIDropDown.Items = roilist;
            app.Data2DropDown.Items = roilist;
            %app.ShowROIsub.Items = roilist;
            %app.ShowROInorm.Items = roilist;

            % initialize structure upstates
            app.ExpStruct(exp).exp(subfield).upstates = ...
                struct('peakindex', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'peakampl', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'peakamplzscore', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),......
                'meanrisingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'maxrisingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'meanfallingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'maxfallingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'auc',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'duration',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'start',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'end',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1));
        end

        % Button pushed function: Loadmasks
        function LoadmasksButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);            
            
            
            FolderName = convertCharsToStrings(app.filepath) + 'ROIs' + '\';  % using my directory                
            outputFilename = FolderName + convertCharsToStrings(extractBefore(app.selection, ".tif")) + '_FIXEDROIs.tif';

            masks = imread(outputFilename);

            app.binarized = masks;

            % take the binarized image and create rois
            edge = bwboundaries(app.binarized);
            newmask = zeros(app.sz(1),app.sz(2),2);

            singl = app.singlemask;
            for i = 1:size(edge,1)

                roi=drawpolygon(app.chAxes,'Position',fliplr(edge{i}));

                newmask(:,:,i) = createMask(roi,singl);
    
                newmask(:,:,i) = logical(newmask(:,:,i));
    
                % roi = [];
                if exist('roi', 'var') == 1
                    delete(roi)
                end
 
            end

            if size(edge,1) == 1
                newmask = newmask(:,:,1);
            end

            app.ExpStruct(exp).exp(subfield).roi = zeros(size(newmask,1),size(newmask,2),size(newmask,3));
            app.ExpStruct(exp).exp(subfield).roi = newmask;                  
            % execute the function to extract the timecourse
            Mask_TimeCourse_Centroid_Extractor(app,exp,subfield)

            % creating and plotting transparent masks
            twoDimTransparencyComp(app,app.ExpStruct(exp).exp(subfield).roi)

            %legend
            Legend(app,app.legendAxes,app.ExpStruct(exp).exp(subfield).roi,app.C)

            % modify the displayed number of rois
            app.NofROIsinthisfield.Value = size(app.ExpStruct(exp).exp(subfield).roi,3);

            % update the list of rois in the cross correlation and roi
            % selector next to plots
            roilist = (1:size(app.ExpStruct(exp).exp(subfield).roi,3));
            roilist = arrayfun(@num2str,roilist,'un',0);
            app.Data1ROIDropDown.Items = roilist;
            app.Data2DropDown.Items = roilist;
            %app.ShowROIsub.Items = roilist;
            %app.ShowROInorm.Items = roilist;

            % initialize structure upstates
            app.ExpStruct(exp).exp(subfield).upstates = ...
                struct('peakindex', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'peakampl', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'peakamplzscore', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),......
                'meanrisingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'maxrisingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'meanfallingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'maxfallingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'auc',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'duration',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'start',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'end',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1));

           
        end

        % Button pushed function: SaveROIsButton
        function SaveROIsButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value); 

            app.LampSaveROI.Color = 'red';         
                
            % save data in excel
            if ~exist(convertCharsToStrings(app.filepath) + 'ROIs', 'dir')
                newfold = mkdir(convertCharsToStrings(app.filepath) + 'ROIs');
            end
            FolderName = convertCharsToStrings(app.filepath) + 'ROIs' + '\';  % using my directory                
            recycle on % Send to recycle bin instead of permanently deleting.
            delete(FolderName + convertCharsToStrings(extractBefore(app.selection, ".tif")) + '_FIXEDROIs.tif'); % Delete (send to recycle bin).
            outputFilename = FolderName + convertCharsToStrings(extractBefore(app.selection, ".tif")) + '_FIXEDROIs.tif';

            imwrite(app.binarized,outputFilename);
            
            app.LampSaveROI.Color = 'green';
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);   

            app.LampSaveRes.Color = 'red'; 

            timecourse = app.ExpStruct(exp).exp(subfield).maskstimecourse';
            withtime = cat(2,app.time',timecourse);

            zsc = zscore(timecourse);
            zsc_withtime = cat(2,app.time',zsc);

            % save data in excel
            if ~exist(convertCharsToStrings(app.filepath) + 'Results', 'dir')
                newfold = mkdir(convertCharsToStrings(app.filepath) + 'Results');
            end
            FolderName = convertCharsToStrings(app.filepath) + 'Results' + '\';  % using my directory                
            recycle on % Send to recycle bin instead of permanently deleting.
            delete(FolderName + convertCharsToStrings(extractBefore(app.selection, ".tif")) + '_FIXEDROIsRes.xlsx'); % Delete (send to recycle bin).
            nm = FolderName + convertCharsToStrings(extractBefore(app.selection, ".tif")) + '_FIXEDROIsRes.xlsx';

            var = array2table(withtime);
            %var.Properties.VariableNames = ["time","signal"];
            writetable(var,nm,'Sheet','Raw')

            var3d = array2table(zsc_withtime);
            %var3d.Properties.VariableNames = ["avgarea3d","maxarea3d","totarea3d"];
            writetable(var3d,nm,'Sheet','Zscore')     


            % create and save figures
            figure(1);
            plot(app.time,zsc)

            figure(2);
            imagesc(zsc')
            set(gca,'clim',[0 4])       

            figure(3);
            imagesc(mean(zsc',1))
            set(gca,'clim',[0 4])   

            %saving figures
            if ~exist(convertCharsToStrings(app.filepath) + 'Figures', 'dir')
                newfold = mkdir(convertCharsToStrings(app.filepath) + 'Figures');
            end
            FolderName = convertCharsToStrings(app.filepath) +  'Figures' + '\';  % using my directory
            FigList = findobj(allchild(0), 'flat', 'Type', 'figure');
            for iFig = 1:3
                FigHandle = FigList(iFig);
                if iFig == 1
                    FigName = convertCharsToStrings(extractBefore(app.selection, ".tif")) + '_ZScore';
                elseif iFig == 2
                    FigName = convertCharsToStrings(extractBefore(app.selection, ".tif")) + '_RasterZScore';
                elseif iFig == 3
                    FigName = convertCharsToStrings(extractBefore(app.selection, ".tif")) + '_RasterAvgZScore'; 
                end
                set(0, 'CurrentFigure', FigHandle);
                %   saveas(FigHandle, strcat(FigName, '.png'));
                saveas(FigHandle, FolderName + FigName + '.png'); % specify the full path
            end

            close all
            
            app.LampSaveRes.Color = 'green'; 
        end

        % Button pushed function: AutodetectionallROIs
        function AutodetectionallROIsButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);   
                      
            res = [];
            for i = 1:size(app.ExpStruct(exp).exp(subfield).subtracted,1)
                app.ShowROInorm.Value = i;
                curROI = app.ShowROInorm.Value;
                ValidateOscButtonPushed(app, event)

                tempres = [(app.ExpStruct(exp).exp(subfield).upstates(curROI).start.*app.samplingperiod)'...
                            (app.ExpStruct(exp).exp(subfield).upstates(curROI).peakindex.*app.samplingperiod)...
                            (app.ExpStruct(exp).exp(subfield).upstates(curROI).end.*app.samplingperiod)'...
                            app.ExpStruct(exp).exp(subfield).upstates(curROI).duration'...
                            app.ExpStruct(exp).exp(subfield).upstates(curROI).peakampl'...
                            app.ExpStruct(exp).exp(subfield).upstates(curROI).peakamplzscore'...
                            app.ExpStruct(exp).exp(subfield).upstates(curROI).auc'...
                            app.ExpStruct(exp).exp(subfield).upstates(curROI).meanrisingslope'...
                            app.ExpStruct(exp).exp(subfield).upstates(curROI).maxrisingslope'...
                            app.ExpStruct(exp).exp(subfield).upstates(curROI).meanfallingslope'...
                            app.ExpStruct(exp).exp(subfield).upstates(curROI).maxfallingslope']; 

                res = cat(1,res,tempres);
            end

            % save data in excel
            if ~exist(convertCharsToStrings(app.filepath) + 'Results', 'dir')
                newfold = mkdir(convertCharsToStrings(app.filepath) + 'Results');
            end
            FolderName = convertCharsToStrings(app.filepath) + 'Results' + '\';  % using my directory                
            recycle on % Send to recycle bin instead of permanently deleting.
            delete(FolderName + convertCharsToStrings(extractBefore(app.selection, ".")) + '_PeakAnalysis.xlsx'); % Delete (send to recycle bin).
            nm = FolderName + convertCharsToStrings(extractBefore(app.selection, ".")) + '_PeakAnalysis.xlsx';

            var = array2table(res);
            var.Properties.VariableNames = ["Start(s)", "Peak(s)", "End(s)", "Duration(s)", "peakampl(deltaF/F)",...
                "peakampl(zscore)",  "auc", "avgrisingslope", "maxrisingslope", "avgfallingslope", "maxfallingslope"];
            
            writetable(var,nm,'Sheet','PeakAnalysis')
            
        end

        % Button pushed function: DeletecurrentROIButton
        function DeletecurrentROIButtonPushed(app, event)
            exp = str2double(app.ExperimentnumberDropDown.Value);
            subfield = str2double(app.FieldnumberDropDown.Value);

            curROI = app.ShowROInorm.Value;

            app.ExpStruct(exp).exp(subfield).roi(:,:,curROI) = [];
            app.ExpStruct(exp).exp(subfield).maskstimecourse(curROI,:) = [];
            app.ExpStruct(exp).exp(subfield).centroid(:,curROI) = [];

            %Mask_TimeCourse_Centroid_Extractor(app,exp,subfield)
            app.PlotRawButtonPushed(event)
            app.PlotDetrendedButtonPushed(event)
            app.PlotNormalizedButtonPushed(event)


            % creating and plotting transparent masks
            twoDimTransparencyComp(app,app.ExpStruct(exp).exp(subfield).roi)

            %legend
            Legend(app,app.legendAxes,app.ExpStruct(exp).exp(subfield).roi,app.C)


            % update the list of rois in the cross correlation and roi
            % selector next to plots
            roilist = (1:size(app.ExpStruct(exp).exp(subfield).roi,3));
            roilist = arrayfun(@num2str,roilist,'un',0);
            app.Data1ROIDropDown.Items = roilist;
            app.Data2DropDown.Items = roilist;
            %app.ShowROIsub.Items = roilist;
            %app.ShowROInorm.Items = roilist;

            % update the number of roiu in main page
            app.NofROIsinthisfield.Value = size(app.ExpStruct(exp).exp(subfield).maskstimecourse,1);

            % initialize structure upstates
            app.ExpStruct(exp).exp(subfield).upstates = ...
                struct('peakindex', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'peakampl', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'peakamplzscore', cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),......
                'meanrisingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'maxrisingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'meanfallingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'maxfallingslope',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'auc',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'duration',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'start',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1),...
                'end',cell(size(app.ExpStruct(exp).exp(subfield).roi,3),1));            
        end

        % Value changed function: ProminenceSwitch
        function ProminenceSwitchValueChanged(app, event)
            value = app.ProminenceSwitch.Value;
            if strcmp(value,'Threshold')
                app.YThresholdLabel.Text = 'Threshold';
            else
                app.YThresholdLabel.Text = 'Prominence';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.VideoPanel = uipanel(app.MainTab);
            app.VideoPanel.Title = 'Video';
            app.greench = uiaxes(app.VideoPanel);
            zlabel(app.greench, 'Z')
            app.greench.XTick = [];
            app.greench.YTick = [];
            app.legendAxes = uiaxes(app.VideoPanel);
            zlabel(app.legendAxes, 'Z')
            app.legendAxes.XTick = [];
            app.legendAxes.YTick = [];
            app.LUTmodifierandsliderPanel = uipanel(app.VideoPanel);
            app.LUTmodifierandsliderPanel.Title = 'LUT modifier and slider';
            app.greenHistoAxes = uiaxes(app.LUTmodifierandsliderPanel);
            app.greenHistoAxes.XLim = [0 4095];
            app.greenHistoAxes.YTick = [];
            app.greenHistoAxes.Tag = 'greenHisto';
            app.greenLUTPanel = uipanel(app.LUTmodifierandsliderPanel);
            app.greenLUTPanel.Title = 'LUT';
            app.minLabel = uilabel(app.greenLUTPanel);
            app.minLabel.Text = 'min';
            app.CMinEditField = uieditfield(app.greenLUTPanel, 'numeric');
            app.CMinEditField.ValueChangedFcn = createCallbackFcn(app, @greenCMinEditFieldValueChanged, true);
            app.maxEditFieldLabel = uilabel(app.greenLUTPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.CMaxEditField = uieditfield(app.greenLUTPanel, 'numeric');
            app.CMaxEditField.ValueChangedFcn = createCallbackFcn(app, @greenCMinEditFieldValueChanged, true);
            app.CMaxEditField.Value = 5000;
            app.SliderLabel = uilabel(app.LUTmodifierandsliderPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LUTmodifierandsliderPanel);
            app.Slider.Limits = [1 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.FourthstepROIsgenerationPanel = uipanel(app.VideoPanel);
            app.FourthstepROIsgenerationPanel.Title = 'Fourth step - ROIs generation';
            app.ThreshSlider = uislider(app.FourthstepROIsgenerationPanel);
            app.ThreshSlider.Limits = [0 4095];
            app.ThreshSlider.ValueChangedFcn = createCallbackFcn(app, @ThreshSliderValueChanged, true);
            app.ThreshSlider.ValueChangingFcn = createCallbackFcn(app, @ThreshSliderValueChanging, true);
            app.autorois = uibutton(app.FourthstepROIsgenerationPanel, 'push');
            app.autorois.ButtonPushedFcn = createCallbackFcn(app, @autoroisButtonPushed, true);
            app.autorois.Text = 'Auto ROIs';
            app.greenThrButtonGroup = uibuttongroup(app.FourthstepROIsgenerationPanel);
            app.greenThrButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @greenThrButtonGroupSelectionChanged, true);
            app.GlobalButton = uiradiobutton(app.greenThrButtonGroup);
            app.GlobalButton.Text = 'Global';
            app.GlobalButton.Value = true;
            app.AdaptiveButton = uiradiobutton(app.greenThrButtonGroup);
            app.AdaptiveButton.Text = 'Adaptive';
            app.nhoodEditFieldLabel = uilabel(app.FourthstepROIsgenerationPanel);
            app.nhoodEditFieldLabel.Text = 'nhood';
            app.nhood = uieditfield(app.FourthstepROIsgenerationPanel, 'numeric');
            app.nhood.Value = 21;
            app.DilateEditFieldLabel = uilabel(app.FourthstepROIsgenerationPanel);
            app.DilateEditFieldLabel.Text = 'Open';
            app.Dilate = uieditfield(app.FourthstepROIsgenerationPanel, 'numeric');
            app.Dilate.Value = 1;
            app.ErodeEditFieldLabel = uilabel(app.FourthstepROIsgenerationPanel);
            app.ErodeEditFieldLabel.Text = 'Erode';
            app.Erode = uieditfield(app.FourthstepROIsgenerationPanel, 'numeric');
            app.Erode.Value = 1;
            app.ManualROIsPanel = uipanel(app.FourthstepROIsgenerationPanel);
            app.ManualROIsPanel.Title = 'Manual ROIs';
            app.DrawROIsButton = uibutton(app.ManualROIsPanel, 'push');
            app.DrawROIsButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIsButtonPushed, true);
            app.DrawROIsButton.Text = 'Draw ROIs';
            app.NofROIsinthisfieldEditFieldLabel = uilabel(app.ManualROIsPanel);
            app.NofROIsinthisfieldEditFieldLabel.Text = 'N of ROIs in this field';
            app.NofROIsinthisfield = uieditfield(app.ManualROIsPanel, 'numeric');
            app.greenDeleteROIsButton = uibutton(app.ManualROIsPanel, 'push');
            app.greenDeleteROIsButton.ButtonPushedFcn = createCallbackFcn(app, @greenDeleteROIsButtonPushed, true);
            app.greenDeleteROIsButton.Text = 'Delete ROIs';
            app.SeeBinarizedButton = uibutton(app.FourthstepROIsgenerationPanel, 'push');
            app.SeeBinarizedButton.ButtonPushedFcn = createCallbackFcn(app, @SeeBinarizedButtonPushed2, true);
            app.SeeBinarizedButton.Text = 'See Binarized';
            app.MinAreaThrLabel = uilabel(app.FourthstepROIsgenerationPanel);
            app.MinAreaThrLabel.Text = 'MinArea Pxl';
            app.MinAreaThr = uieditfield(app.FourthstepROIsgenerationPanel, 'numeric');
            app.MinAreaThr.Value = 100;
            app.SaveROIsButton = uibutton(app.FourthstepROIsgenerationPanel, 'push');
            app.SaveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIsButtonPushed, true);
            app.SaveROIsButton.Text = 'Save ROIs';
            app.SaveResultsButton = uibutton(app.FourthstepROIsgenerationPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.LampSaveROI = uilamp(app.FourthstepROIsgenerationPanel);
            app.LampSaveRes = uilamp(app.FourthstepROIsgenerationPanel);
            app.FirststepUploadPanel = uipanel(app.MainTab);
            app.FirststepUploadPanel.Title = 'First step - Upload';
            app.SelectfolderandfileButton = uibutton(app.FirststepUploadPanel, 'push');
            app.SelectfolderandfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderandfileButtonPushed, true);
            app.SelectfolderandfileButton.Text = 'Select folder and file';
            app.DatatypeDropDownLabel = uilabel(app.FirststepUploadPanel);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.Datatype = uidropdown(app.FirststepUploadPanel);
            app.Datatype.Items = {'TIF list', 'multiTIF (one exp)', 'multiTIF (multi exp) (coming soon)'};
            app.Datatype.ValueChangedFcn = createCallbackFcn(app, @DatatypeValueChanged, true);
            app.Datatype.Value = 'multiTIF (one exp)';
            app.filename = uieditfield(app.FirststepUploadPanel, 'text');
            app.filenametext = uilabel(app.FirststepUploadPanel);
            app.filenametext.Visible = 'off';
            app.filenametext.Text = 'Filename:';
            app.Loadmasks = uibutton(app.FirststepUploadPanel, 'push');
            app.Loadmasks.ButtonPushedFcn = createCallbackFcn(app, @LoadmasksButtonPushed, true);
            app.Loadmasks.Text = 'Load ROIs';
            app.FPSEditField_3Label_3 = uilabel(app.FirststepUploadPanel);
            app.FPSEditField_3Label_3.Text = 'FPS';
            app.FPSEditFieldMain = uieditfield(app.FirststepUploadPanel, 'numeric');
            app.FPSEditFieldMain.Value = 10;
            app.ExperimentnumberDropDownLabel = uilabel(app.FirststepUploadPanel);
            app.ExperimentnumberDropDownLabel.Text = 'Experiment number';
            app.ExperimentnumberDropDown = uidropdown(app.FirststepUploadPanel);
            app.ExperimentnumberDropDown.Items = {'1', '2', '3', '4'};
            app.ExperimentnumberDropDown.Value = '1';
            app.FieldnumberDropDownLabel = uilabel(app.FirststepUploadPanel);
            app.FieldnumberDropDownLabel.Text = 'Field number';
            app.FieldnumberDropDown = uidropdown(app.FirststepUploadPanel);
            app.FieldnumberDropDown.Items = {'1', '2', '3', '4'};
            app.FieldnumberDropDown.Value = '1';
            app.SecondstepoptionalcorrofbigillchangesPanel = uipanel(app.MainTab);
            app.SecondstepoptionalcorrofbigillchangesPanel.Title = 'Second step (optional) - corr. of big ill. changes';
            app.DetrendingButton = uibutton(app.SecondstepoptionalcorrofbigillchangesPanel, 'push');
            app.DetrendingButton.ButtonPushedFcn = createCallbackFcn(app, @DetrendingButtonPushed, true);
            app.DetrendingButton.Text = 'Detrending';
            app.LampSubAvg = uilamp(app.SecondstepoptionalcorrofbigillchangesPanel);
            app.SeezprojButton = uibutton(app.SecondstepoptionalcorrofbigillchangesPanel, 'push');
            app.SeezprojButton.ButtonPushedFcn = createCallbackFcn(app, @SeezprojButtonPushed, true);
            app.SeezprojButton.Text = 'See zproj';
            app.DetrendOrdMain = uieditfield(app.SecondstepoptionalcorrofbigillchangesPanel, 'numeric');
            app.DetrendOrdMain.Value = 1;
            app.ThirdstepfilteringanddifferentialPanel = uipanel(app.MainTab);
            app.ThirdstepfilteringanddifferentialPanel.Title = 'Third step - filtering and differential';
            app.High1 = uieditfield(app.ThirdstepfilteringanddifferentialPanel, 'numeric');
            app.High1.Value = 0.3;
            app.FIlterLabel = uilabel(app.ThirdstepfilteringanddifferentialPanel);
            app.FIlterLabel.Text = 'BPFilter';
            app.Low1 = uieditfield(app.ThirdstepfilteringanddifferentialPanel, 'numeric');
            app.FilterorderEditFieldLabel = uilabel(app.ThirdstepfilteringanddifferentialPanel);
            app.FilterorderEditFieldLabel.Text = 'Filter order';
            app.FilterOrder = uieditfield(app.ThirdstepfilteringanddifferentialPanel, 'numeric');
            app.FilterOrder.Value = 6;
            app.LowpassanddifferentialButton = uibutton(app.ThirdstepfilteringanddifferentialPanel, 'push');
            app.LowpassanddifferentialButton.ButtonPushedFcn = createCallbackFcn(app, @LowpassanddifferentialButtonPushed, true);
            app.LowpassanddifferentialButton.Text = 'Lowpass and differential';
            app.LampFilterMain = uilamp(app.ThirdstepfilteringanddifferentialPanel);
            app.SeediffButton = uibutton(app.ThirdstepfilteringanddifferentialPanel, 'push');
            app.SeediffButton.ButtonPushedFcn = createCallbackFcn(app, @SeediffButtonPushed, true);
            app.SeediffButton.Text = 'See diff';
            app.RawprofilesTab = uitab(app.TabGroup);
            app.RawprofilesTab.Title = 'Raw profiles';
            app.RawTimeseries = uiaxes(app.RawprofilesTab);
            xlabel(app.RawTimeseries, 'Time(s)')
            ylabel(app.RawTimeseries, 'Fluorescence')
            app.TextArea = uitextarea(app.RawprofilesTab);
            app.TextArea.Value = {'This step is just for visualization and eventually to cut the timeseries, no strict need to display raw profiles'};
            app.PlotRawButton = uibutton(app.RawprofilesTab, 'push');
            app.PlotRawButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawButtonPushed, true);
            app.PlotRawButton.Text = 'Plot Raw';
            app.CropPanel = uipanel(app.RawprofilesTab);
            app.CropPanel.Title = 'Crop';
            app.StartsEditFieldLabel = uilabel(app.CropPanel);
            app.StartsEditFieldLabel.Text = 'Start (s):';
            app.StartsEditField = uieditfield(app.CropPanel, 'numeric');
            app.EndsEditFieldLabel = uilabel(app.CropPanel);
            app.EndsEditFieldLabel.Text = 'End (s):';
            app.EndsEditField = uieditfield(app.CropPanel, 'numeric');
            app.EndsEditField.Value = 1;
            app.CROPButton = uibutton(app.CropPanel, 'push');
            app.CROPButton.ButtonPushedFcn = createCallbackFcn(app, @CROPButtonPushed, true);
            app.CROPButton.Text = 'CROP';
            app.RestoreButton = uibutton(app.CropPanel, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore';
            app.ProfilesTab = uitab(app.TabGroup);
            app.ProfilesTab.Title = 'Profiles';
            app.VisualizationanddataextractionPanel = uipanel(app.ProfilesTab);
            app.VisualizationanddataextractionPanel.Title = 'Visualization and data extraction';
            app.NormalizedTimeseries = uiaxes(app.VisualizationanddataextractionPanel);
            xlabel(app.NormalizedTimeseries, 'Time(s)')
            ylabel(app.NormalizedTimeseries, 'ZScore')
            app.SubtractedTimeseries = uiaxes(app.VisualizationanddataextractionPanel);
            xlabel(app.SubtractedTimeseries, 'Time(s)')
            ylabel(app.SubtractedTimeseries, 'Fluorescence')
            app.ResultAxes = uiaxes(app.VisualizationanddataextractionPanel);
            xlabel(app.ResultAxes, 'Time(s)')
            ylabel(app.ResultAxes, 'Fluorescence')
            zlabel(app.ResultAxes, 'Z')
            app.PlotDetrendedButton = uibutton(app.VisualizationanddataextractionPanel, 'push');
            app.PlotDetrendedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDetrendedButtonPushed, true);
            app.PlotDetrendedButton.Text = 'Plot Detrended';
            app.PlotNormalizedButton = uibutton(app.VisualizationanddataextractionPanel, 'push');
            app.PlotNormalizedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotNormalizedButtonPushed, true);
            app.PlotNormalizedButton.Text = 'Plot Normalized';
            app.DetrendorderEditFieldLabel = uilabel(app.VisualizationanddataextractionPanel);
            app.DetrendorderEditFieldLabel.Text = 'Detrend order';
            app.Detrendorder = uieditfield(app.VisualizationanddataextractionPanel, 'numeric');
            app.Detrendorder.Value = 1;
            app.SavenormalizedButton = uibutton(app.VisualizationanddataextractionPanel, 'push');
            app.SavenormalizedButton.ButtonPushedFcn = createCallbackFcn(app, @SavenormalizedButtonPushed, true);
            app.SavenormalizedButton.Text = 'Save normalized';
            app.ShowROInSpinnerLabel = uilabel(app.VisualizationanddataextractionPanel);
            app.ShowROInSpinnerLabel.Text = 'Show ROI n°';
            app.ShowROInorm = uispinner(app.VisualizationanddataextractionPanel);
            app.ShowROInorm.ValueChangedFcn = createCallbackFcn(app, @ShowROInormValueChanged, true);
            app.ShowROInorm.Value = 1;
            app.ShowROInSpinner_2Label = uilabel(app.VisualizationanddataextractionPanel);
            app.ShowROInSpinner_2Label.Text = 'Show ROI n°';
            app.ShowROIsub = uispinner(app.VisualizationanddataextractionPanel);
            app.ShowROIsub.ValueChangedFcn = createCallbackFcn(app, @ShowROIsubValueChanged, true);
            app.ShowROIsub.Value = 1;
            app.OscillatoryactivityPanel = uipanel(app.VisualizationanddataextractionPanel);
            app.OscillatoryactivityPanel.Title = 'Oscillatory activity';
            app.ValidateOsc = uibutton(app.OscillatoryactivityPanel, 'push');
            app.ValidateOsc.ButtonPushedFcn = createCallbackFcn(app, @ValidateOscButtonPushed, true);
            app.ValidateOsc.Text = 'Automatic detection';
            app.AvgFwindowsEditFieldLabel = uilabel(app.OscillatoryactivityPanel);
            app.AvgFwindowsEditFieldLabel.Text = 'AvgF window (s)';
            app.AvgFwindow = uieditfield(app.OscillatoryactivityPanel, 'numeric');
            app.AvgFwindow.Tooltip = {'Seconds to take around the peak to caclulate peak amplitude'};
            app.AvgFwindow.Value = 1;
            app.FilteringPanel = uipanel(app.OscillatoryactivityPanel);
            app.FilteringPanel.Title = 'Filtering';
            app.SeeButton = uibutton(app.FilteringPanel, 'push');
            app.SeeButton.ButtonPushedFcn = createCallbackFcn(app, @SeeButtonPushed, true);
            app.SeeButton.Text = 'See ';
            app.High2 = uieditfield(app.FilteringPanel, 'numeric');
            app.High2.Value = 0.3;
            app.BPFilterEditFieldLabel = uilabel(app.FilteringPanel);
            app.BPFilterEditFieldLabel.Text = 'BPFilter';
            app.Low2 = uieditfield(app.FilteringPanel, 'numeric');
            app.FilterOrderLabel = uilabel(app.FilteringPanel);
            app.FilterOrderLabel.Text = 'Filter Order';
            app.FilterOrder2 = uieditfield(app.FilteringPanel, 'numeric');
            app.FilterOrder2.Value = 6;
            app.YThresholdLabel = uilabel(app.OscillatoryactivityPanel);
            app.YThresholdLabel.Text = 'Threshold';
            app.Ythreshold = uieditfield(app.OscillatoryactivityPanel, 'numeric');
            app.Ythreshold.ValueChangedFcn = createCallbackFcn(app, @YthresholdValueChanged, true);
            app.Ythreshold.Tooltip = {'Threshold for the identification of upstates'};
            app.Ythreshold.Value = 2;
            app.ManualpeakPanel = uipanel(app.OscillatoryactivityPanel);
            app.ManualpeakPanel.Title = 'Manual peak';
            app.OscStart = uieditfield(app.ManualpeakPanel, 'numeric');
            app.OscEnd = uieditfield(app.ManualpeakPanel, 'numeric');
            app.window = uieditfield(app.ManualpeakPanel, 'text');
            app.window.Value = 'WINDOW';
            app.ValidateWindow = uibutton(app.ManualpeakPanel, 'push');
            app.ValidateWindow.ButtonPushedFcn = createCallbackFcn(app, @ValidateWindowButtonPushed, true);
            app.ValidateWindow.Text = 'Validate';
            app.AutodetectionallROIs = uibutton(app.OscillatoryactivityPanel, 'push');
            app.AutodetectionallROIs.ButtonPushedFcn = createCallbackFcn(app, @AutodetectionallROIsButtonPushed, true);
            app.AutodetectionallROIs.Text = 'Auto detection all ROIs';
            app.ProminenceSwitch = uiswitch(app.OscillatoryactivityPanel, 'slider');
            app.ProminenceSwitch.Items = {'Threshold', 'Prominence'};
            app.ProminenceSwitch.ValueChangedFcn = createCallbackFcn(app, @ProminenceSwitchValueChanged, true);
            app.ProminenceSwitch.Value = 'Threshold';
            app.MinDistancesLabel = uilabel(app.OscillatoryactivityPanel);
            app.MinDistancesLabel.Text = 'Min Distance (s)';
            app.MinDistance = uieditfield(app.OscillatoryactivityPanel, 'numeric');
            app.MinDistance.Value = 5;
            app.ROIscleaningPanel = uipanel(app.VisualizationanddataextractionPanel);
            app.ROIscleaningPanel.Title = 'ROIs cleaning ';
            app.ThresholdzscoreEditFieldLabel = uilabel(app.ROIscleaningPanel);
            app.ThresholdzscoreEditFieldLabel.Text = 'Threshold (zscore)';
            app.ThresholdROItimecourse = uieditfield(app.ROIscleaningPanel, 'numeric');
            app.ThresholdROItimecourse.Value = 2;
            app.RemoveallsubthrROIsButton = uibutton(app.ROIscleaningPanel, 'push');
            app.RemoveallsubthrROIsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveallsubthrROIsButtonPushed, true);
            app.RemoveallsubthrROIsButton.Text = 'Remove all subthr ROIs';
            app.DeletecurrentROIButton = uibutton(app.ROIscleaningPanel, 'push');
            app.DeletecurrentROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeletecurrentROIButtonPushed, true);
            app.DeletecurrentROIButton.Text = 'Delete current ROI';
            app.TextArea_3 = uitextarea(app.VisualizationanddataextractionPanel);
            app.TextArea_3.Value = {'If detrend order = 0 there is no detrending. Do not detrend here if you detrended before'};
            app.Tabs = uitab(app.TabGroup);
            app.Tabs.Title = 'Tabs';
            app.TimeseriesTable = uitable(app.Tabs);
            app.TimeseriesTable.ColumnName = {''; ''};
            app.TimeseriesTable.RowName = {};
            app.greenCopyTable = uibutton(app.Tabs, 'push');
            app.greenCopyTable.ButtonPushedFcn = createCallbackFcn(app, @greenCopyTableButtonPushed, true);
            app.greenCopyTable.Text = {'Copy Table'; ''};
            app.ResultsTable = uitable(app.Tabs);
            app.ResultsTable.ColumnName = {''; ''};
            app.ResultsTable.RowName = {};
            app.greenCopyTable_2 = uibutton(app.Tabs, 'push');
            app.greenCopyTable_2.ButtonPushedFcn = createCallbackFcn(app, @greenCopyTable_2ButtonPushed, true);
            app.greenCopyTable_2.Text = {'Copy Table'; ''};
            app.ROInEditFieldLabel = uilabel(app.Tabs);
            app.ROInEditFieldLabel.Visible = 'off';
            app.ROInEditFieldLabel.Text = 'ROI n°';
            app.ROInEditField = uieditfield(app.Tabs, 'numeric');
            app.ROInEditField.Visible = 'off';
            app.infoTextAreaLabel = uilabel(app.Tabs);
            app.infoTextAreaLabel.Text = 'info';
            app.infoTextArea = uitextarea(app.Tabs);
            app.infoTextArea.Value = {'If you are in mode Detr-Norm the first two values are the coordinates of the centroids (x and y).'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MiniMandrake

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end